classdef Register < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Reg3DV212WukongUIFigure     matlab.ui.Figure
        Menu_File                   matlab.ui.container.Menu
        Menu_LoadFile               matlab.ui.container.Menu
        Menu_SaveImageAs            matlab.ui.container.Menu
        Menu_SaveTimeAs             matlab.ui.container.Menu
        Menu_SaveMovieAs            matlab.ui.container.Menu
        Menu_Quit                   matlab.ui.container.Menu
        Menu_Image                  matlab.ui.container.Menu
        Menu_LightContrast          matlab.ui.container.Menu
        Menu_ZProjection            matlab.ui.container.Menu
        Menu_MaxZProjection         matlab.ui.container.Menu
        Menu_MinZProjection         matlab.ui.container.Menu
        Menu_MedianZProjection      matlab.ui.container.Menu
        Menu_MeanZProjection        matlab.ui.container.Menu
        Menu_ChannelAlignment       matlab.ui.container.Menu
        Menu_RemoveBackGround       matlab.ui.container.Menu
        Menu_RemoveBackgroundAuto   matlab.ui.container.Menu
        Menu_RemoveBackgroundFix    matlab.ui.container.Menu
        Menu_MaskManager_Simply     matlab.ui.container.Menu
        Menu_GenerateMask_Simply    matlab.ui.container.Menu
        Menu_LoadMask_Simply        matlab.ui.container.Menu
        Menu_Comments               matlab.ui.container.Menu
        Menu_TimeStamp              matlab.ui.container.Menu
        Menu_ScaleBar               matlab.ui.container.Menu
        Menu_MetaMarker             matlab.ui.container.Menu
        Menu_Crop3D                 matlab.ui.container.Menu
        Menu_CropXY                 matlab.ui.container.Menu
        Menu_CropZ                  matlab.ui.container.Menu
        Menu_CropT                  matlab.ui.container.Menu
        Menu_Options                matlab.ui.container.Menu
        Menu_SaveSetting            matlab.ui.container.Menu
        Menu_LoadSetting            matlab.ui.container.Menu
        Menu_Animation              matlab.ui.container.Menu
        Menu_1F                     matlab.ui.container.Menu
        Menu_5F                     matlab.ui.container.Menu
        Menu_25F                    matlab.ui.container.Menu
        Menu_KeyMode                matlab.ui.container.Menu
        Menu_Direct                 matlab.ui.container.Menu
        Menu_Loop                   matlab.ui.container.Menu
        Menu_Register               matlab.ui.container.Menu
        Menu_RegOptions             matlab.ui.container.Menu
        Menu_RegRange               matlab.ui.container.Menu
        Menu_RegEvaluate            matlab.ui.container.Menu
        Menu_RunModal               matlab.ui.container.Menu
        Menu_RunModalDistributed    matlab.ui.container.Menu
        Menu_RunModalExclusive      matlab.ui.container.Menu
        Menu_TaskViewer             matlab.ui.container.Menu
        Menu_Advanced               matlab.ui.container.Menu
        Menu_PyPath                 matlab.ui.container.Menu
        Menu_MaskManager_Multiply   matlab.ui.container.Menu
        Menu_GenerateMask_Multiply  matlab.ui.container.Menu
        Menu_LoadMask_Multiply      matlab.ui.container.Menu
        Menu_SignalExtractor        matlab.ui.container.Menu
        Menu_Help                   matlab.ui.container.Menu
        Menu_About                  matlab.ui.container.Menu
        Menu_Documents              matlab.ui.container.Menu
        Menu_Language               matlab.ui.container.Menu
        Menu_Chinese                matlab.ui.container.Menu
        Menu_English                matlab.ui.container.Menu
        Menu_SubmitBug              matlab.ui.container.Menu
        GridLayout8                 matlab.ui.container.GridLayout
        Panel_SliceViewer           matlab.ui.container.Panel
        GridLayout_Viewer           matlab.ui.container.GridLayout
        Label_ImageInfo             matlab.ui.control.Label
        Slider_Time                 matlab.ui.control.Slider
        Label                       matlab.ui.control.Label
        GridLayout7                 matlab.ui.container.GridLayout
        GridLayout_OuterPB          matlab.ui.container.GridLayout
        GridLayout_ProgressBar      matlab.ui.container.GridLayout
        Label_ProgressBar           matlab.ui.control.Label
        UIAxes_Slice                matlab.ui.control.UIAxes
        GridLayout5                 matlab.ui.container.GridLayout
        Button_HistoryManager       matlab.ui.control.Button
        GridLayout6                 matlab.ui.container.GridLayout
        Image                       matlab.ui.control.Image
        Image_Update                matlab.ui.control.Image
        Spinner_Slice               matlab.ui.control.Spinner
        GridLayout                  matlab.ui.container.GridLayout
        Spinner_Time                matlab.ui.control.Spinner
        Slider_Slice                matlab.ui.control.Slider
        Label_2                     matlab.ui.control.Label
        Panel_Control               matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        GridLayout4                 matlab.ui.container.GridLayout
        Button_Run                  matlab.ui.control.StateButton
        DropDown_Source             matlab.ui.control.DropDown
        Label_19                    matlab.ui.control.Label
        GridLayout3                 matlab.ui.container.GridLayout
        Label_20                    matlab.ui.control.Label
        TextArea_Log                matlab.ui.control.TextArea
        Switch_Log                  matlab.ui.control.RockerSwitch
        ContextMenu_Player          matlab.ui.container.ContextMenu
        Menu_Horizontal             matlab.ui.container.Menu
        Menu_Vertical               matlab.ui.container.Menu
        Menu_ROI                    matlab.ui.container.Menu
        Menu_RectangleROI           matlab.ui.container.Menu
        Menu_CircleROI              matlab.ui.container.Menu
        Menu_PolygonROI             matlab.ui.container.Menu
        Menu_FreehandROI            matlab.ui.container.Menu
        Menu_Channel                matlab.ui.container.Menu
        ContextMenu_Controller      matlab.ui.container.ContextMenu
        Menu_ExportLog              matlab.ui.container.Menu
        Menu_ClearLog               matlab.ui.container.Menu
        Menu_AutoClearLog           matlab.ui.container.Menu
    end

    properties (GetAccess = ?BugReporter, Constant)
        VER = "2.1.2"
        BUG_REPORT_HOST = "192.168.21.73:2222"
        BUG_REPORT_FOLDER = "/data/LiWeiHan/Reg3D bugs collection/"
        EMAIL_TO = "liweihan20@mails.ucas.ac.cn"
    end

    properties(Constant, Hidden)
        SUBAPP_SNAME = ["BC", "BR", "CC", "EV", "ME", "FS", "HM", ... 
            "MG", "RS", "SB", "SE", "TV"]
    end

    properties (Access = public)
        % dynamic components
        Menu_MergeSource            matlab.ui.container.Menu
        Menu_MergeNone              matlab.ui.container.Menu
        Menu_MergeRB                matlab.ui.container.Menu
        Menu_MergeGR                matlab.ui.container.Menu
        Menu_Red                    matlab.ui.container.Menu
        Menu_Green                  matlab.ui.container.Menu
    end

    % inpersistant data, reset after loading
    properties (Access = private, Hidden)       
        % data source
        img_raw             % 1-by-1 regmov object, the raw movie info
        img_aligned         % 1-by-1 regmov object, the aligned movie info
        mask_reg            % m-by-n-by-p regmask object, with mROI mask
        refvol              % 1-by-1 regtmpl object
        opt_reg             % 1-by-1 regopt object
        opt_sig             % 1-by-1 sigopt object

        img_zproj           % 1-by-1 struct, with field {raw, aligned}, the z-projected images
        fixdef              % 1-by-1 struct, with the fixed defination and channel
        opt_locked          % 1-by-p table, flag for registration options setting done
        flag_distrib        % 1-by-1 logical, the distribution mode flag
        frames_reg          % 1-by-2 string array, the registration frames, [auto, manual]
        roi_viewer_cur      % 1-by-1 struct, the current ROI, field with xy, z and f(frame)

        % viewing/operation position indicator/information
        fidx_cur            % 1-by-1 positive integer, the current frame index
        sidx_cur            % 1-by-1 positive integer, the current slice index
        cidx_cur            % 1-by-1 positive integer, the current channel index
        simg_cur            % m-by-n array, the current slice image(transformed) for viewing
        mROI_hl             % 1-by-s logical array, indicate the highlight components
        c_order             % 1-by-c string, the channels order
        viewer_opts         % 1-by-1 struct, with field {b, c, ga}, the viewer options
        viewer_src          % 1-by-1 string, could be "raw" or "aligned"
        movie_config        % 1-by-1 struct, the exported movie configuration
        scroll_ylim         % 1-by-2 double, the y position limitation of scroll image
        pbx_old             % 1-by-1 double, the old progress value

        % viewing/playing mode
        mode_zproj          % the flag for current viewing mode, "none", "max","min","mean","median"
        mode_merge          % 1-by-1 string, the mode for raw/aligned merge viewing
        mode_loop           % the viewing loop mode, "one-step"/"loop"
        flag_calign         % the color aligned flag, true or false
        flag_bkg_removed    % the signal channel background removed flag
        flag_playing        % flag for movie is playing, true/false
        fps                 % 1-by-1 struct, with field {base, fold} 

        % image tools handles
        hdle_image          % 1-by-1 image object
        hdle_uROI           % 1-by-1 images.roi object
        hdle_mROI           % 1-by-n cell, each item is images.roi object
        text_time           % the time text info struct, {handle, text, ismoving}
        text_meta           % the meta text info struct, {handle, text, ismoving}
        sbar                % 1-by-1 scalebar object

        % app configurations
        filename            % record the processing file name
        path_save           % the file saving path
        flag_server         % the flag for estimating if running on server
        region              % the region setting struct, {plat, lang, chmap}

        % subapp handles
        BC_handle           % brightness and contrast setting, sub app handle
        CC_handle           % the channel alignment handle
        EV_handle           % the evaluation handle
        FS_handle           % the frames selecter handle
        HM_handle           % the history manager handle
        ME_handle           % the movie exporter handle
        MG_handle           % the mask generator handle
        RS_handle           % the registration setting handle
        SB_handle           % the scale bar setting handle
        SE_handle           % the signal estimator handle
        TV_handle           % the task viewer handle

        % system management tools/flags
        % error_msg         % inner error message
        kernel              % 1-by-1 RegistrationController object    
        player_timer        % timer for viewing animation
        progress_timer      % timer for progress bar running indeterminatlly
        loaded_flag         % flag for data loading success
        masked_flag         % flag for data masked, could be ["none","uROI","mROI"]
        recycle_old_status  % 1-by-1 string, record the recycle status before running loading
        img_operators       % string array for recording operations
        sysmsg              % record system messages history, [cmd, sfx, time]
        mdrp                % the masked data recovery point
        update_opts         % the options for update app
        sftp_to_labnas      % the handle of sftp object to labnas
    end

    % persistant data, independent on data
    properties (Access = private, Hidden)   
        % system management tools/flags
        auto_clear_log_flag = false    % flag for log auto clear (triggered after new data loaded)

        % subapp handles
        BR_handle           % the bug reporter handle
    end

   %=================== INTERFACE FOR SUBAPP ============================
   methods(Access = ?BCAdjuster)
       function SetBCG(app, params)
            app.viewer_opts = params;

            % refresh viewer for real time applying
            RefreshViewer(app);
        end

        function params = GetBCG(app)
            params = app.viewer_opts;
        end

        function slice_img = GetCurrentSliceImg(app)
            slice_img = GetSliceImgAt(app);
        end
   end

   methods(Access = {?OCRegSetter, ?TCRegSetter})
       function SetRegOptions(app, regopt_, fixdef_, locked_)
            app.opt_reg = regopt_;
            app.fixdef = fixdef_;
            app.opt_locked = locked_;

            % synchronize update refvol
            switch app.viewer_src
                case "raw"
                    app.refvol = regtmpl(app.img_raw, app.fixdef);
                case "aligned"
                    app.refvol = regtmpl(app.img_aligned, app.fixdef);
                otherwise
            end
        end

        function [regopt_, fixdef_, locked_] = GetRegOptions(app)
            regopt_ = app.opt_reg;
            fixdef_ = app.fixdef;
            locked_ = app.opt_locked;
        end

        function refvol = GetRefVol(app)
            switch app.viewer_src
                case "raw"
                    refvol = regtmpl(app.img_raw, app.fixdef);
                case "aligned"
                    refvol = regtmpl(app.img_aligned, app.fixdef);
                otherwise
            end
        end
   end

   methods(Access = {?scalebar, ?ScaleBar})
       function SetScaleBar(app, scale, bar, font)
            app.sbar.Scale = scale;
            app.sbar.Bar = bar;
            app.sbar.Font = font;

            % refresh scalebar for real time applying
            app.sbar.clear();
            app.sbar.draw();
        end

        function sbar = GetScaleBar(app)
            sbar = app.sbar;
        end
   end

   methods(Access = ?SignalEstimator)
       function SetSigOptions(app, sigopt_)
           arguments
               app
               sigopt_  (1,1)   sigopt
           end

           app.opt_sig = sigopt_;
       end

       function r = GetSigOptions(app)
           r = app.opt_sig;
       end

       function r = GetSignalChannel(app)
           r = app.opt_reg.Options.FC;
       end

       function r = GetMask(app)
           switch app.masked_flag
               case "mROI"
                   r = app.mask_reg;
               otherwise
                   r = [];
           end
       end

       function r = GetCurrentSource(app)
           switch app.viewer_src
               case "raw"
                   r = app.img_raw;
               case "aligned"
                   r = app.img_aligned;
               otherwise
                   % never come here
                   r = regmov.empty();
           end
       end

       function HighlightROI(app, r)
           % This function highlight the ROI with r marked
           arguments
               app
               r    (1,:)   double {mustBePositive, mustBeInteger}
           end

           color = jet(app.mask_reg.NumComps);

           for k = 1:numel(app.hdle_mROI)
               mroi_idx = app.hdle_mROI{k}.UserData;
               if ismember(mroi_idx, r)
                   % highlight this roi
                   app.hdle_mROI{k}.Color = color(mroi_idx, :);
                   app.hdle_mROI{k}.LabelVisible = "on";
                   app.hdle_mROI{k}.Selected = true;
                   app.hdle_mROI{k}.LabelTextColor = 1-color(mroi_idx, :); % three bofd design
               else
                   % remove roi highlight
                   app.hdle_mROI{k}.Color = [1, 1, 1];
                   app.hdle_mROI{k}.LabelVisible = "hover";
                   app.hdle_mROI{k}.Selected = false;
                   app.hdle_mROI{k}.LabelTextColor = [0, 0, 0]; % black for high contrast
               end
           end

           app.mROI_hl = false(size(app.mROI_hl));
           app.mROI_hl(r) = true;
       end

       function RenameROI(app, ridx, rname)
           arguments
               app
               ridx (1,1)   double {mustBePositive, mustBeInteger}
               rname(1,1)   string
           end

           app.mask_reg.MaskLabel(ridx) = rname;

           for k = 1:numel(app.hdle_mROI)
               if app.hdle_mROI{k}.UserData == ridx
                   % rename this roi
                   app.hdle_mROI{k}.Label = rname;
                   break;
               end
           end
       end
   end

   methods(Access = ?Evaluator)
       function tform = GetTransformation(app)
           tform = app.img_aligned.Transformation;
       end

       function [mov_raw, mov_align] = GetMoviePair(app)
           mov_raw = squeeze(app.img_raw.Movie(:,:,app.cidx_cur,:,:));
           mov_align = squeeze(app.img_aligned.Movie(:,:,app.cidx_cur,:,:));
       end

       function r = GetTemplate(app)
           r = app.refvol.RefVol;
       end
   end

   methods(Access = {?Evaluator, ?BCAdjuster})
       function r = GetCurrentChannel(app)
           r = app.c_order(app.cidx_cur);
       end
   end

   methods(Access = ?MaskGenerator)
       function z = GetCurrentSliceIndex(app)
           z = app.sidx_cur;
       end

       function r = GetROI(app)
           if ~isempty(app.hdle_uROI) && isvalid(app.hdle_uROI)
               r = app.hdle_uROI;
           else
               r = [];
           end
       end
   end

   methods(Access = ?FrameSelecter)
       function r = GetCurrentFrameIndex(app)
           r = app.fidx_cur;
       end

       function SetFrames(app, indices)
           app.roi_viewer_cur.f = indices;
       end
   end

   methods(Access = {?MaskGenerator,?MovieExporter,?SignalEstimator})
       function r = GetFileName(app)
           r = string(app.filename);
       end
   end

   methods(Access = ?ChannelCorrector)
       function SetChannelAlignmentView(app, shift)
           UpdateCurrentSlice(app, "CC", shift);
           DrawCurrentSlice(app);
       end

       function SetChannelAlignment(app, shift)
           if any(shift ~= 0)
               selection = uiconfirm(app.Reg3DV212WukongUIFigure,...
                   app.region.chmap.IRREVERSIBLE_CA,...
                   app.region.chmap.WARNING, "Icon","warning");
               if selection ~= "OK"
                   return;
               else
                   PanelWaiting(app);

                   % apply channel shift on data
                   RA = imref3d([app.img_raw.MetaData.height, ...
                       app.img_raw.MetaData.width, ...
                       app.img_raw.MetaData.slices], ...
                       app.img_raw.MetaData.xRes, ...
                       app.img_raw.MetaData.yRes, ...
                       app.img_raw.MetaData.zRes);
                   rot = [cosd(shift(3)), sind(shift(3)), 0; ...
                       -sind(shift(3)), cosd(shift(3)), 0; ...
                       0, 0, 1];
                   tform = rigidtform3d(rot, [shift(1:2), 0]);
                   [~, cidx] = ismember(app.opt_reg.Options.FC, app.img_raw.MetaData.cOrder);
                   fill_val = prctile(app.img_raw.Movie(:,:,cidx,:,1), 10, "all");
                   t_span = sprintf("1:%d",app.img_raw.MetaData.frames);

                   bar = uiprogressdlg(app.Reg3DV212WukongUIFigure,...
                       "Title",app.region.chmap.INFORMATION, ...
                       "Message",app.region.chmap.CHANNEL_ALIGNING,...
                       "Indeterminate","on", "Icon","info");
                   drawnow;

                   parpool("Threads");

                   % align functional channel to structured channel

                   tmpmov = grv(app.img_raw, ["none", t_span], ...
                       app.opt_reg.Options.FC);

                   parfor t = 1:app.img_raw.MetaData.frames
                       tmp_data = tmpmov(:,:,:,t);
                       tmpmov(:,:,:,t) =  imwarp(tmp_data, RA, tform,...
                           "linear","FillValues",fill_val, "OutputView",RA);
                   end

                   srv(app.img_raw, tmpmov, ["none", t_span], ...
                       app.opt_reg.Options.FC);

                   if ~isempty(app.img_aligned)
                       tmpmov = grv(app.img_aligned, ["none", t_span], ...
                           app.opt_reg.Options.FC);

                       parfor t = 1:app.img_aligned.MetaData.frames
                           tmp_data = tmpmov(:,:,:,t);
                           tmpmov(:,:,:,t) =  imwarp(tmp_data, RA, tform,...
                               "linear","FillValues",fill_val, "OutputView",RA);
                       end

                       srv(app.img_aligned, tmpmov, ["none", t_span], ...
                           app.opt_reg.Options.FC);
                   end

                   delete(gcp("nocreate"));

                   close(bar);

                   % Synchronize z projection
                   SourceZProjection(app, [], app.mode_zproj);

                   PanelRecovered(app);

                   % RefreshViewer for viewing recovery
                   RefreshViewer(app);
               end
           end
       end
   end

   methods(Access = ?MovieExporter)
       function r = GetMovieConfig(app)
           r = app.movie_config;
       end
       function SetMovieConfig(app, x)
           arguments
               app
               x    (1,1)   struct
           end

           app.movie_config = x;
       end
   end

   methods(Access = ?TaskViewer)
       function r = GetEngineInfo(app)
           r = app.kernel.SysInfo;
       end

       function SetNWorkersProtected(app, x)
           arguments
               app
               x   (1,2)   double  {mustBeNonnegative, mustBeInteger}
           end
           app.kernel.NWorkersProtected = x;
       end

       function r = GetEngineStatus(app)
           r = app.kernel.State;
       end
   end

   methods (Access = public)
        
        function info = GetImgInfo(app)
            info = app.img_raw.MetaData;
        end

        function [lang, mapping] = GetLangsInfo(app)
            lang = app.region.lang;
            mapping = app.region.chmap;
        end

        function SendMessage(app, id, timeStamp)
            arguments
                app
                id          (1,2)   string
                timeStamp   (1,1)   logical = true
            end
            
            GenSysMsg(app, id, true, timeStamp);
        end

        function SetProgressBar(app, x, iterinf)
            arguments
                app
                x       (1,1)   double  {mustBeInRange(x, 0, 1)} = 0
                iterinf (1,1)   logical = false
            end

            if ~iterinf
                stop(app.progress_timer);
                app.pbx_old = x;
                len = app.Reg3DV212WukongUIFigure.Position(3) - (60*2+10*4+7*2);
                app.GridLayout_ProgressBar.ColumnWidth = {0, round(x*len)};
                drawnow
            else
                if x == 0
                    start(app.progress_timer);
                end
            end
        end
    end
    
    %=========================== APP METHODS =============================
    methods (Access = private)
        %====================== INIT COMPONENTS ==========================
        
        function InitData(app)
            % init movie info
            app.img_raw = regmov.empty();
            app.img_aligned = regmov.empty();
            app.img_zproj = struct("raw",[], "aligned",[], "ref", []);
            app.mask_reg = [];

            % init registration options
            app.fixdef = struct("Sampling", ["mean", "1"], ...
                                "Channel",  "r"); 
            app.refvol = [];
            app.opt_reg = regopt("TCREG", "global");
            app.opt_sig = sigopt("MovingQuantile", "Constant");
            app.opt_locked = table('Size', [1,4], ...
                'VariableTypes', repmat({'logical'},1,4), ...
                'VariableNames', {'Global','Local','MGlobal','LTGlobal'});

            % init register kernel
            app.kernel = RegisterController(app, app.opt_reg, ...
                [1, 0], app.flag_distrib);

            % init ROI 
            app.roi_viewer_cur = struct("xy",[],"z",[],"f",[]);

            % init app private variables
            app.fidx_cur = [];
            app.sidx_cur = [];
            app.cidx_cur = [];
            app.simg_cur = [];
            app.c_order = [];
            app.viewer_opts = struct("b",0,"c",1,"ga",1);
            app.hdle_image = [];
            app.hdle_uROI = [];
            app.hdle_mROI = {};
            app.mROI_hl = [];
            app.text_time = struct("handle",[],"text",[],"ismoving",[]);
            app.text_meta = struct("handle",[],"text",[],"ismoving",[]);
            app.sbar = scalebar(app.UIAxes_Slice);
            app.mode_zproj = "none";
            app.mode_merge = "";
            app.flag_calign = false;
            app.flag_bkg_removed = false;
            app.viewer_src = "";
            app.frames_reg = [];
            app.flag_playing = false;
            app.fps = struct("fold",1,"base",[]);
            app.path_save = "";
            app.pbx_old = 0;

            app.loaded_flag = false;
            app.masked_flag = "none";
            app.img_operators = "none";
            app.mdrp = struct("raw",[],"aligned",[]);
            app.update_opts = struct("is_update",false,...
                "repository","","auto_clean",false);
        end

        function results = DeployEnvConfig(app)
            % check java path
            % check C/C++ compiler
            javachk('jvm');
            javachk('swing');

            mex -setup C
            if GetAvailableMemory() <= 2.7e10
                % low memory, must use distributed mode
                app.flag_distrib = true;
            else
                app.flag_distrib = false;
            end
            pause(0.5);
            clc;

            if (feature('numCores') > 16) && (GetAvailableMemory() > 1.375e11) ...
                    && isunix()
                % more than 16 cores and 128GB available memory
                % server must use 'distributed' mode
                app.flag_server = true;
                app.flag_distrib = true;
                disp("Server mode enabled: too many CPU cores and memory installed.");
            else
                app.flag_server = false;
            end

            pe = pyenv;
            if app.flag_server == true
                % try to configurate the python environment
                try
                    pyenv(Version="/usr/share/miniconda3/bin/python3", ...
                        ExecutionMode="OutOfProcess");
                catch
                    warning("Register:pyenvConfigFailed", ...
                        "Can't configurate python environment, try to restart MATLAB.");
                    delete(app);
                end
                app.Menu_PyPath.Checked = "on";
            else
                if pe.Version ~= ""
                    try
                        pyenv(ExecutionMode="OutOfProcess");
                    catch
                        warning("Register:pyenvConfigFailed", ...
                            "Can't configurate python environment, try to restart MATLAB.");
                        delete(app);
                    end
                    app.Menu_PyPath.Checked = "on";
                else
                    % python interpreter not found
                    app.Menu_PyPath.Checked = "off";
                end
            end

            % close some warning
            warning('off', 'MATLAB:timer:miliSecPrecNotAllowed');

            % store the recycle configuration
            app.recycle_old_status = recycle();
            recycle("off");     % save disk space

            results = 0;
        end

        function InitTimer(app)
            % init player timer
            app.player_timer = timer("Period",1,...
                "ExecutionMode","fixedSpacing","TasksToExecute",inf,...
                "Name","Timer_PlayControl","TimerFcn",@app.Play,"StopFcn",@app.Stop);

            % init progress bar timer
            app.progress_timer = timer("Period",0.01,...
                "ExecutionMode","fixedSpacing","TasksToExecute",inf,...
                "Name", "Timer_ProgressControl","TimerFcn",@app.IndeteminateProgressBar);
        end

        function UpdateRegOptionsAfterLoading(app)
            if app.img_raw.MetaData.channels == 1
                app.opt_reg = regopt("OCREG", "global");
                app.opt_locked = table('Size', [1,3], ...
                    'VariableTypes', repmat({'logical'},1,3), ...
                    'VariableNames', {'Global','Local','MGlobal'});
            else
                app.opt_reg = regopt("TCREG", "global");
                app.opt_locked = table('Size', [1,4], ...
                    'VariableTypes', repmat({'logical'},1,4), ...
                    'VariableNames', {'Global','Local','MGlobal','LTGlobal'});
                
            end
            app.opt_reg = set(app.opt_reg, ...
                "VPL", floor(log(app.img_raw.MetaData.slices)/log(4))+1);

            app.refvol = regtmpl(app.img_raw, app.fixdef);
        end

        function InitPanels(app)
            InitMenu(app);

            InitSlider_Slice(app);

            InitSlider_Time(app);

            InitContextMenu(app);

            InitControlPanel(app);

            InitUIAxes(app);
        end

        function UpdatePanelsAfterLoading(app)
            % dynamic context menu
            RemoveMergeChannelMenu(app);
            RemoveColorChannelMenu(app);
            AppendColorChannelMenu(app);

            % unlock the options
            app.Menu_SaveImageAs.Enable = "on";
            app.Menu_SaveTimeAs.Enable = "on";
            app.Menu_SaveMovieAs.Enable = "on";
            app.Menu_Image.Enable = "on";
            if app.img_raw.MetaData.channels == 1
                app.Menu_ChannelAlignment.Enable = "off";
            elseif app.img_raw.MetaData.channels == 2
                app.Menu_ChannelAlignment.Enable = "on";
            end
            app.Menu_RemoveBackgroundAuto.Checked = "off";
            app.Menu_RemoveBackgroundFix.Checked = "off";
            app.Menu_Animation.Enable = "on";
            app.Menu_Horizontal.Enable = "on";
            app.Menu_Vertical.Enable = "on";
            app.Menu_ROI.Enable = "on";
            app.Menu_Channel.Enable = "on";
            app.Menu_MaxZProjection.Checked = "off";
            app.Menu_MinZProjection.Checked = "off";
            app.Menu_MeanZProjection.Checked = "off";
            app.Menu_MedianZProjection.Checked = "off";
            app.Menu_MaskManager_Simply.Enable = "on";
            app.Menu_LoadMask_Simply.Text = app.region.chmap.IMPORT_MASK;
            app.Menu_TimeStamp.Checked = "off";
            app.Menu_MetaMarker.Checked = "off";
            app.Menu_Register.Enable = "on";
            app.Menu_KeyMode.Enable = "on";
            app.Menu_1F.Checked = "on";
            app.Menu_5F.Checked = "off";
            app.Menu_25F.Checked = "off";
            app.Menu_RegOptions.Enable = "on";
            app.Menu_RegRange.Enable = "on";
            app.Menu_MaskManager_Multiply.Enable = "on";
            app.Menu_LoadMask_Multiply.Text = app.region.chmap.IMPORT_MASK;
            app.DropDown_Source.Enable = "on";
            app.DropDown_Source.Items = app.region.chmap.RAW;
            app.Button_Run.Enable = "on";
            app.SetProgressBar(0);

            % clear log if auto flag is true
            if app.auto_clear_log_flag
                ClearTextArea_Log(app);
                app.sysmsg = [];
            end

            GenSysMsg(app, ["FILE_LOADING_COMPLETE", " "+app.filename]);
        end

        function SetSystemLanguage(app, lang)
            if exist("lang","var") && ~isempty(lang)
                UpdateRegion(app, lang);
            else
                app.region = GetRegionInfo();
                UpdateRegion(app, app.region.lang);
            end

            % apply menu language
            app.Menu_File.Text = app.region.chmap.FILE;
            app.Menu_Image.Text = app.region.chmap.IMAGE;
            app.Menu_Options.Text = app.region.chmap.OPTION;
            app.Menu_Advanced.Text = app.region.chmap.ADVANCED;
            app.Menu_Help.Text = app.region.chmap.HELP;
            app.Menu_LoadFile.Text = app.region.chmap.LOAD_FILE;
            app.Menu_SaveImageAs.Text = app.region.chmap.EXPORT_IMAGES;
            app.Menu_SaveTimeAs.Text = app.region.chmap.EXPORT_TIME;
            app.Menu_SaveMovieAs.Text = app.region.chmap.EXPORT_MOVIE;
            app.Menu_Quit.Text = app.region.chmap.EXIT;
            app.Menu_LightContrast.Text = app.region.chmap.LIGHT_CONTRAST;
            app.Menu_ChannelAlignment.Text = app.region.chmap.CHANNEL_ALIGNMENT;
            app.Menu_RemoveBackGround.Text = app.region.chmap.REMOVE_BACKGROUND;
            app.Menu_RemoveBackgroundAuto.Text = app.region.chmap.AUTO;
            app.Menu_RemoveBackgroundFix.Text = app.region.chmap.FIXED;
            app.Menu_ZProjection.Text = app.region.chmap.Z_PROJECTION;
            app.Menu_MaskManager_Simply.Text = app.region.chmap.MASK_SIMPLY;
            app.Menu_Comments.Text = app.region.chmap.APPEND_COMMENTS;
            app.Menu_Crop3D.Text = app.region.chmap.CROP_VOLUME;
            app.Menu_CropT.Text = app.region.chmap.CROP_FRAME;
            app.Menu_SaveSetting.Text = app.region.chmap.EXPORT_CONFIGURATION;
            app.Menu_LoadSetting.Text = app.region.chmap.LOAD_CONFIGURATION;
            app.Menu_Animation.Text = app.region.chmap.ANIMATION;
            app.Menu_KeyMode.Text = app.region.chmap.PLAY_MODE;
            app.Menu_Register.Text = app.region.chmap.REGISTRATOR;
            app.Button_HistoryManager.Text ...
                = app.region.chmap.HISTORY_MANAGER.replace("",newline);
            app.Menu_RunModal.Text = app.region.chmap.RUN_MODAL;
            app.Menu_RunModalDistributed.Text = app.region.chmap.DISTRIBUTED;
            app.Menu_RunModalExclusive.Text = app.region.chmap.EXCLUSIVE;
            app.Menu_TaskViewer.Text = app.region.chmap.TASK_VIEWER;
            app.Menu_PyPath.Text = app.region.chmap.PYTHON_INTERPRETER;
            app.Menu_MaskManager_Multiply.Text = app.region.chmap.MASK_MULTIPLY;
            app.Menu_SignalExtractor.Text = app.region.chmap.SIGNAL_EXTRACTOR;
            app.Menu_About.Text = app.region.chmap.ABOUT;
            app.Menu_Documents.Text = app.region.chmap.DOCUMENT;
            app.Menu_Language.Text = app.region.chmap.LANGUAGE;
            app.Menu_SubmitBug.Text = app.region.chmap.SUBMIT_BUG;
            app.Menu_MaxZProjection.Text = app.region.chmap.MAX_Z_PROJECTION;
            app.Menu_MinZProjection.Text = app.region.chmap.MIN_Z_PROJECTION;
            app.Menu_MedianZProjection.Text = app.region.chmap.MEDIAN_Z_PROJECTION;
            app.Menu_MeanZProjection.Text = app.region.chmap.MEAN_Z_PROJECTION;
            app.Menu_GenerateMask_Simply.Text = app.region.chmap.GENERATE_MASK;
            app.Menu_GenerateMask_Multiply.Text = app.region.chmap.GENERATE_MASK;
            app.Menu_LoadMask_Simply.Text = app.region.chmap.IMPORT_MASK;
            app.Menu_LoadMask_Multiply.Text = app.region.chmap.IMPORT_MASK;
            app.Menu_TimeStamp.Text = app.region.chmap.TIME_STAMP;
            app.Menu_ScaleBar.Text = app.region.chmap.SCALE_RATIO;
            app.Menu_MetaMarker.Text = app.region.chmap.METADATA;
            app.Menu_CropXY.Text = app.region.chmap.CROP_XY;
            app.Menu_CropZ.Text = app.region.chmap.CROP_Z;
            app.Menu_Direct.Text = app.region.chmap.DIRECT;
            app.Menu_Loop.Text = app.region.chmap.RECYCLE;
            app.Menu_RegOptions.Text = app.region.chmap.REGISTRATION_SETTING;
            app.Menu_RegRange.Text = app.region.chmap.REGISTRATION_RANGE;
            app.Menu_RegEvaluate.Text = app.region.chmap.REGISTRATION_EVALUATION;

            % apply main panel language
            app.Panel_SliceViewer.Title = app.region.chmap.SLICE_VIEWER;
            app.Panel_Control.Title = app.region.chmap.CONTROL_PANEL;
            app.Label_2.Text = app.region.chmap.SLICE;
            app.Label.Text = app.region.chmap.TIME_FRAME;
            app.Label_19.Text = app.region.chmap.SOURCE;
            app.Label_20.Text = app.region.chmap.LOG;
            app.Switch_Log.Items = [app.region.chmap.OFF, app.region.chmap.ON];
            if numel(app.DropDown_Source.Items) == 1
                app.DropDown_Source.Items = app.region.chmap.RAW;
            elseif numel(app.DropDown_Source.Items) == 2
                app.DropDown_Source.Items = [app.region.chmap.RAW, ...
                                             app.region.chmap.ALIGNED];
            end
            app.Button_Run.Text = app.region.chmap.RUN;

            % apply static context menu language
            app.Menu_Horizontal.Text = app.region.chmap.HORIZONTAL_FLIP;
            app.Menu_Vertical.Text = app.region.chmap.VERTICAL_FLIP;
            app.Menu_ROI.Text = app.region.chmap.ROI_SETTING;
            app.Menu_Channel.Text = app.region.chmap.COLOR_CHANNEL;
            app.Menu_RectangleROI.Text = app.region.chmap.RECTANGLE;
            app.Menu_CircleROI.Text = app.region.chmap.CIRCLE;
            app.Menu_PolygonROI.Text = app.region.chmap.POLYGON;
            app.Menu_FreehandROI.Text = app.region.chmap.REGION_FREE;
            app.Menu_ExportLog.Text = app.region.chmap.EXPORT;
            app.Menu_ClearLog.Text = app.region.chmap.CLEAR;
            app.Menu_AutoClearLog.Text = app.region.chmap.AUTO_CLEAR;

            % apply dynamic context menu language
            if ~isempty(app.Menu_MergeSource) && isvalid(app.Menu_MergeSource)
                app.Menu_MergeSource.Text = app.region.chmap.MERGE_SOURCE;
                app.Menu_MergeNone.Text = app.region.chmap.NONE;
                app.Menu_MergeRB.Text = app.region.chmap.RED_CYAN;
                app.Menu_MergeGR.Text = app.region.chmap.GREEN_MAGENTA;
            end
            if ~isempty(app.Menu_Red) && isvalid(app.Menu_Red)
                app.Menu_Red.Text = app.region.chmap.RED;
            end
            if ~isempty(app.Menu_Green) && isvalid(app.Menu_Green)
                app.Menu_Green.Text = app.region.chmap.GREEN;
            end

            % translate the log message
            if ~isempty(app.TextArea_Log.Value{1}) && app.TextArea_Log.Value{1} ~= ""
                if exist("lang","var") && ~isempty(lang)
                    for k = 1:numel(app.TextArea_Log.Value)
                        if app.sysmsg(k, 3) ~= ""
                            app.TextArea_Log.Value{k} = [app.sysmsg(k, 3).char(), ...
                                ' ', app.region.chmap.(app.sysmsg(k, 1)).char(), ...
                                app.sysmsg(k, 2).char()];
                        else
                            app.TextArea_Log.Value{k} = ...
                                [app.region.chmap.(app.sysmsg(k, 1)).char(), ...
                                 app.sysmsg(k, 2).char()];
                        end
                    end
                end
            end

            % apply sub app language
            RefreshAllSubAppLanguage(app);

        end

        function InitMenu(app)
            app.Menu_SaveImageAs.Enable = "off";
            app.Menu_SaveTimeAs.Enable = "off";
            app.Menu_SaveMovieAs.Enable = "off";
            app.Menu_Image.Enable = "off";
            app.Menu_RemoveBackgroundAuto.Checked = "off";
            app.Menu_RemoveBackgroundFix.Checked = "off";
            app.Menu_RegOptions.Enable = "off";
            app.Menu_RegEvaluate.Enable = "off";
            app.Menu_RegRange.Enable = "off";
            app.Menu_Register.Enable = "off";
            app.Button_HistoryManager.Enable = "off";
            app.Menu_Animation.Enable = "off";
            app.Menu_MaxZProjection.Checked = "off";
            app.Menu_MinZProjection.Checked = "off";
            app.Menu_MeanZProjection.Checked = "off";
            app.Menu_MedianZProjection.Checked = "off";
            app.Menu_MaskManager_Multiply.Enable = "off";
            app.Menu_SignalExtractor.Enable = "off";

            app.Menu_KeyMode.Enable = "off";
            app.Menu_Direct.Checked = "on";
            app.Menu_Loop.Checked = "off";
            app.Menu_1F.Checked = "on";
            app.Menu_5F.Checked = "off";
            app.Menu_25F.Checked = "off";
            switch app.region.lang
                case "zh_CN"
                    app.Menu_Chinese.Checked = "on";
                case "en_US"
                    app.Menu_English.Checked = "on";
                otherwise
                    app.Menu_English.Checked = "on";
            end

            if isunix(); app.Menu_SubmitBug.Enable = "off"; end

            if app.flag_distrib == true
                % unix or server
                app.Menu_RunModalDistributed.Checked = "on";
                app.Menu_RunModalExclusive.Checked = "off";
            else
                % high performance pc(Windows), exclusive is possible
                app.Menu_RunModalDistributed.Checked = "off";
                app.Menu_RunModalExclusive.Checked = "on";
            end
        end

        function InitContextMenu(app)
            app.Menu_Horizontal.Enable = "off";
            app.Menu_Vertical.Enable = "off";
            app.Menu_ROI.Enable = "off";
            app.Menu_Channel.Enable = "off";

            % dynamic context menu
            RemoveMergeChannelMenu(app);
            RemoveColorChannelMenu(app);
        end

        function InitSlider_Time(app)
            app.Slider_Time.Limits = [0,1];
            app.Slider_Time.Value = 0;
            app.Slider_Time.Enable = "off";
            app.Spinner_Time.Enable = "off";
        end

        function InitControlPanel(app)
            app.DropDown_Source.Enable = "off";
            app.Button_Run.Enable = "off";
            app.mode_loop = "one-step";
        end

        function InitUIAxes(app)
            app.UIAxes_Slice.Visible = "off";
        end

        function InitSlider_Slice(app)
            app.Slider_Slice.Limits = [0,1];
            app.Slider_Slice.Value = 0;
            app.Slider_Slice.Enable = "off";
            app.Spinner_Slice.Enable = "off";
        end
        
        function im_H = InitUIAxes_Slice(app)
            app.UIAxes_Slice.Toolbar = [];
            % disable main UIAxes interaction to avoid mistake operation
            disableDefaultInteractivity(app.UIAxes_Slice);

            app.UIAxes_Slice.XLim = [0, app.img_raw.MetaData.width];
            app.UIAxes_Slice.YLim = [0, app.img_raw.MetaData.height];

            pbaspect(app.UIAxes_Slice, [app.img_raw.MetaData.width, ...
                                        app.img_raw.MetaData.height,...
                                        1]);

            im_H = image(app.UIAxes_Slice,...
                [0 app.img_raw.MetaData.width],...
                [0 app.img_raw.MetaData.height],...
                [],"Interpolation","bilinear"); % bilinear offer better visual effort

            app.hdle_image = im_H;

            colormap(app.UIAxes_Slice,"gray");
            drawnow

            UpdateYScrollLim(app);

            app.UIAxes_Slice.Visible = "on";
            app.Label_ImageInfo.Visible = "on";
        end
        
        %======================= PLAYER CONTROL ==========================
        function UpdateYScrollLim(app)
            % calculate scroll y limitation
            tp = app.UIAxes_Slice.tightPosition();
            % xr = diff(app.UIAxes_Slice.XLim)/tp(3)*app.Panel_Control.Position(3);
            yr = diff(app.UIAxes_Slice.YLim)/tp(4)*app.Panel_SliceViewer.Position(4);
            % xvalid = 0.5*diff(app.UIAxes_Slice.XLim) + 0.5*[-xr, xr];
            tiset = app.UIAxes_Slice.TightInset;
            app.scroll_ylim = 0.5*diff(app.UIAxes_Slice.YLim) + ...
                0.5*[-yr, yr] + 0.5*[tiset(2)+20, tiset(4)];
        end

        function ScalingProgressBar(app)
            % update progress bar
            figwc = app.Reg3DV212WukongUIFigure.Position(3);
            lnew = (figwc - (60*2+10*4+7*2))*app.pbx_old;
            app.GridLayout_ProgressBar.ColumnWidth{2} = lnew;
            drawnow
        end

        function UpdatePlayerTimer(app, nframes, fps)
            if ~isempty(app.player_timer) && isvalid(app.player_timer)
                app.player_timer.Period = 1/fps;
                app.player_timer.TasksToExecute = nframes;
            end
        end
        
        function NextFramePtr(app)
            if ~isempty(app.fidx_cur) && app.flag_calign == false
                if app.fidx_cur < app.Slider_Time.Limits(end)
                    app.fidx_cur = app.fidx_cur + 1;
                else
                    switch app.mode_loop
                        case "one-step"
                            % do nothing
                        case "loop"
                            app.fidx_cur = app.Slider_Time.Limits(1);
                    end
                end
                app.Slider_Time.Value = app.fidx_cur;
                app.Spinner_Time.Value = app.fidx_cur;
                app.frames_reg(2) = string(app.fidx_cur);
            end
        end

        function PrevFramePtr(app)
            if ~isempty(app.fidx_cur) && app.flag_calign == false
                if app.fidx_cur > app.Slider_Time.Limits(1)
                    app.fidx_cur = app.fidx_cur - 1;
                else
                    switch app.mode_loop
                        case "one-step"
                            % do nothing
                        case "loop"
                            app.fidx_cur = app.Slider_Time.Limits(end);
                    end
                end
                app.Slider_Time.Value = app.fidx_cur;
                app.Spinner_Time.Value = app.fidx_cur;
                app.frames_reg(2) = string(app.fidx_cur);
            end
        end

        function NextSlicePtr(app)
            if ~isempty(app.sidx_cur) && app.mode_zproj == "none" ...
                    && app.flag_calign == false
                if app.sidx_cur < app.Slider_Slice.Limits(end)
                    app.sidx_cur = app.sidx_cur + 1;
                else
                    switch app.mode_loop
                        case "one-step"
                            % do nothing
                        case "loop"
                            app.sidx_cur = app.Slider_Slice.Limits(1);
                    end
                end
                app.Slider_Slice.Value = app.sidx_cur;
                app.Spinner_Slice.Value = app.sidx_cur;
            end
        end

        function PrevSlicePtr(app)
            if ~isempty(app.sidx_cur) && app.mode_zproj == "none" ...
                    && app.flag_calign == false
                if app.sidx_cur > app.Slider_Slice.Limits(1)
                    app.sidx_cur = app.sidx_cur - 1;
                else
                    switch app.mode_loop
                        case "one-step"
                            % do nothing
                        case "loop"
                            app.sidx_cur = app.Slider_Slice.Limits(end);
                    end
                end
                app.Slider_Slice.Value = app.sidx_cur;
                app.Spinner_Slice.Value = app.sidx_cur;
            end
        end

        function Play(app, src, event)
            src; %#ok<VUNUS>
            event; %#ok<VUNUS>
            if ~app.flag_calign
                NextFramePtr(app);
                RefreshViewer(app, "T");
            end
        end

        function Stop(app, src, event)
            src; %#ok<VUNUS>
            event; %#ok<VUNUS>
            UnlockPlaySetting(app);
        end

        function RefreshViewer(app, dview)
            arguments
                app
                dview   (1,1)   string {mustBeMember(dview, ["XY", "Z","T"])} = "T"
            end

            if ~app.flag_calign
                % update and draw current slice
                UpdateCurrentSlice(app);
                DrawCurrentSlice(app);

                % update and draw mROI objects, text objects
                DrawUtilsComponents(app, dview);

                % follow the mouse
                Reg3DV212WukongUIFigureWindowButtonMotion(app);
            end
        end

        function LockViewerController(app)
            app.Slider_Slice.Enable = "off";
            app.Spinner_Slice.Enable = "off";
            app.Slider_Time.Enable = "off";
            app.Spinner_Time.Enable = "off";
            app.Menu_Animation.Enable = "off";
            app.Menu_Horizontal.Enable = "off";
            app.Menu_Vertical.Enable = "off";
            app.Menu_Channel.Enable = "off";
        end

        function UnlockViewerController(app)
            if app.mode_zproj == "none"
                app.Slider_Slice.Enable = "on";
                app.Spinner_Slice.Enable = "on";
            end
            app.Slider_Time.Enable = "on";
            app.Spinner_Time.Enable = "on";
            app.Menu_Animation.Enable = "on";
            app.Menu_Horizontal.Enable = "on";
            app.Menu_Vertical.Enable = "on";
            app.Menu_Channel.Enable = "on";
        end
        
        %===================== PLAYER SETTING ============================
        
        % player components: lock play mode menu
        function LockPlaySetting(app)
            app.Menu_Loop.Enable = "off";
            app.Menu_Direct.Enable = "off";
            app.Menu_1F.Enable = "off";
            app.Menu_5F.Enable = "off";
            app.Menu_25F.Enable = "off";
        end

        % player components: unlock play mode menu
        function UnlockPlaySetting(app)
            app.Menu_Loop.Enable = "on";
            app.Menu_Direct.Enable = "on";
            app.Menu_1F.Enable = "on";
            app.Menu_5F.Enable = "on";
            app.Menu_25F.Enable = "on";
        end

        % player components: time slider
        function SetSlider_Time(app, limits)
            LABEL_COUNTS = 20;
            app.Slider_Time.Enable = "on";
            app.Spinner_Time.Enable = "on";

            if ~isempty(app.img_raw) && app.Slider_Time.Enable == true
                if ~isempty(limits)
                    app.Slider_Time.Limits = limits;
                    app.Spinner_Time.Limits = limits;
                else
                    % keep the limits
                    limits = app.Slider_Time.Limits;
                end

                deltaT = round((limits(2)-limits(1))/LABEL_COUNTS);
                if ~isequal(app.Slider_Time.MajorTicks,limits(1):deltaT:limits(2))
                    app.Slider_Time.MajorTicks = limits(1):deltaT:limits(2);
                    app.Slider_Time.MajorTickLabels = string(app.Slider_Time.MajorTicks);
                end

                app.Slider_Time.Value = app.fidx_cur;
                app.Spinner_Time.Value = app.fidx_cur;
            end
        end

        % player components: slice slider
        function SetSlider_Slice(app, state)
            if exist("state","var") && ~isempty(state)
                switch state
                    case 'on'
                        app.Slider_Slice.Enable = "on";
                        app.Spinner_Slice.Enable = "on";
                    case 'off'
                        app.Slider_Slice.Limits = [0,1];
                        app.Slider_Slice.Value = 0;
                        app.Slider_Slice.Enable = "off";
                        app.Spinner_Slices.Limits = [0,1];
                        app.Spinner_Slices.Value = 0;
                        app.Spinner_Slice.Enable = "off";
                    case 'freeze'
                        app.Slider_Slice.Enable = "off";
                        app.Spinner_Slice.Enable = "off";
                end
            else
                % keep the state
            end

            if ~isempty(app.img_raw)
                app.Slider_Slice.Limits = [1, app.img_raw.MetaData.slices];
                app.Spinner_Slice.Limits = [1, app.img_raw.MetaData.slices];

                app.Slider_Slice.MajorTicks = 1:5:app.img_raw.MetaData.slices;
                app.Slider_Slice.MinorTicks = 1:app.img_raw.MetaData.slices;

                app.Slider_Slice.Value = app.sidx_cur;
                app.Spinner_Slice.Value = app.sidx_cur;
            end
        end

        function IndeteminateProgressBar(app, obj, event)
            % This function set the progress bar running indeteminatlly
            obj; %#ok<VUNUS>
            event; %#ok<VUNUS>
            persistent x;
            if isempty(x)
                x = 0;
            else
                x = mod(x+1, 60);
            end
            len = app.Reg3DV212WukongUIFigure.Position(3) - (60*2+10*4+7*2);
            barl = round(min([len/5, x/50*len, (60-x)*len/50]));
            bars = round(max(x*len/50-len/5, 0));

            app.GridLayout_ProgressBar.ColumnWidth = {bars, barl};
            drawnow nocallbacks
        end

        % player components: viewer window
        function DrawCurrentSlice(app)
            if ~isempty(app.simg_cur)
                % update the world coordinate
                xlim(app.UIAxes_Slice,[0,app.img_raw.MetaData.width]);
                ylim(app.UIAxes_Slice,[0,app.img_raw.MetaData.height]);

                % primary version for fast rendering
                % fill the new image
                set(app.hdle_image,'CData',app.simg_cur);

                % reshape the im_handle (image shape)
                set(app.hdle_image,'XData',[0,app.img_raw.MetaData.width]);
                set(app.hdle_image,'YData',[0,app.img_raw.MetaData.height]);

                % keep the width-height ratio
                pbaspect(app.UIAxes_Slice,[size(app.simg_cur,[2,1]),1]);

                % update image immediately
                drawnow nocallbacks
            end
        end

        % player components: viewer title
        function UpdateUIAxes_Slice_Title(app,cursorPos,grayVal,roi)
            if ~exist("cursorPos","var") || isempty(cursorPos)
                cursorPos = 'None';
            else
                cursorPos = ['(',num2str(cursorPos(1)),',',...
                    num2str(cursorPos(2)),')'];
            end
            if ~exist("grayVal","var") || isempty(grayVal)
                grayVal = 'None';
            else
                grayVal = num2str(grayVal);
            end
            if ~exist("roi","var") || isempty(roi)
                roi = "None";
            else
                roi = ['w = ',num2str(roi(1)),', h = ',num2str(roi(2))];
            end

            app.Label_ImageInfo.Text = "Cursor: " + cursorPos + ...
                ",   Value: " + grayVal + ...
                ",   Time: "  + sprintf("%.1f", app.img_raw.Time(app.fidx_cur)) + " s" + ...
                ",   ROI: "   + roi;
        end

        % player components: dynamic add merge channel
        function AppendMergeChannelMenu(app)
            % construct the parent menu
            app.Menu_MergeSource = uimenu(app.ContextMenu_Player);
            app.Menu_MergeSource.Enable = "on";
            app.Menu_MergeSource.Text = app.region.chmap.MERGE_SOURCE;

            % construct the children menu
            app.Menu_MergeNone = uimenu(app.Menu_MergeSource);
            app.Menu_MergeNone.MenuSelectedFcn = createCallbackFcn(app, @Menu_MergeNoneSelected, true);
            app.Menu_MergeNone.Enable = "on";
            app.Menu_MergeNone.Text = app.region.chmap.NONE;
            app.Menu_MergeNone.Checked = "on";
            %
            app.Menu_MergeRB = uimenu(app.Menu_MergeSource);
            app.Menu_MergeRB.MenuSelectedFcn = createCallbackFcn(app, @Menu_MergeRBSelected, true);
            app.Menu_MergeRB.Enable = "on";
            app.Menu_MergeRB.Text = app.region.chmap.RED_CYAN;
            app.Menu_MergeRB.Checked = "off";
            %
            app.Menu_MergeGR = uimenu(app.Menu_MergeSource);
            app.Menu_MergeGR.MenuSelectedFcn = createCallbackFcn(app, @Menu_MergeGRSelected, true);
            app.Menu_MergeGR.Enable = "on";
            app.Menu_MergeGR.Text = app.region.chmap.GREEN_MAGENTA;
            app.Menu_MergeGR.Checked = "off";
        end

        % player components: dynamic remove merge channel
        function RemoveMergeChannelMenu(app)
            if ~isempty(app.Menu_MergeSource) ...
                    && isvalid(app.Menu_MergeSource)
                delete(app.Menu_MergeSource);
            end
        end

        % player components: dynamic add color channel
        function AppendColorChannelMenu(app)
            if ismember("r", app.c_order)
                app.Menu_Red = uimenu(app.Menu_Channel);
                app.Menu_Red.MenuSelectedFcn = createCallbackFcn(app, @Menu_RedSelected, true);
                app.Menu_Red.Enable = "on";
                app.Menu_Red.Text = app.region.chmap.RED;
                app.Menu_Red.Checked = "off";
            end
            if ismember("g", app.c_order)
                app.Menu_Green = uimenu(app.Menu_Channel);
                app.Menu_Green.MenuSelectedFcn = createCallbackFcn(app, @Menu_GreenSelected, true);
                app.Menu_Green.Enable = "on";
                app.Menu_Green.Text = app.region.chmap.GREEN;
                app.Menu_Green.Checked = "off";
            end
            switch app.c_order(app.cidx_cur)
                case "r"
                    app.Menu_Red.Checked = "on";
                case "g"
                    app.Menu_Green.Checked = "on";
                otherwise
                    error(app.region.chmap.UNSUPPORTED_CHANNEL_NUMBER);
            end
        end

        % player components: dynamic remove color channel
        function RemoveColorChannelMenu(app)
            if ~isempty(app.Menu_Red) && isvalid(app.Menu_Red)
                delete(app.Menu_Red);
            end
            if ~isempty(app.Menu_Green) && isvalid(app.Menu_Green)
                delete(app.Menu_Green);
            end
        end

        %==================== CONTROL PANEL SETTING ======================

        function ClearTextArea_Log(app)
            % clear log buffer
            if ~isempty(app.TextArea_Log.Value)
                app.TextArea_Log.Value = "";
            end
        end

        function ExportTextArea_Log(app, path)
            t = datetime('now','TimeZone','local','Format','yyMMdd_HHmmss');
            fname = fullfile(path, ['log_',char(t),'.txt']);
            writecell(app.TextArea_Log.Value, fname);
        end

        %====================== INDICATE SET/GET =========================
        function timeMsg = AppendTextArea_Log(app, msg, usingTimeStamp)
            if app.Switch_Log.Value == "off"
                return;
            end

            if ~exist('usingTimeStamp','var')
                usingTimeStamp = false;
            end

            if usingTimeStamp == true
                % Append message on text area
                timeMsg =string(datetime("now")).char();
                Msg = [timeMsg, ' ', char(msg)];
            else
                timeMsg = '';
                Msg = char(msg);
            end
            if isempty(app.TextArea_Log.Value{1})
                app.TextArea_Log.Value={Msg};
            else
                app.TextArea_Log.Value = [app.TextArea_Log.Value;{Msg}];
            end

            % scroll to bottom auto
            scroll(app.TextArea_Log, "bottom");
        end

        %========================== DATA UPDATE ==========================

        function status = DataConstructor(app, mov, opts, rt)
            % This function load data to app workspace
            % =============== VALIDATE DATA FORMATION ================
            if isempty(opts) || isempty(mov), status = -1; return; end

            if isempty(rt), rt = 1:opts.frames; end

            if opts.frames < 2
                uialert(app.Reg3DV212WukongUIFigure,...
                    app.region.chmap.UNSUPPORTED_FEW_FRAMES,...
                    app.region.chmap.WARNING,"Icon","warning");
                status = -2;
                return;
            end

            if opts.slices < 5
                uialert(app.Reg3DV212WukongUIFigure,...
                    app.region.chmap.UNSUPPORTED_FEW_LAYERS,...
                    app.region.chmap.WARNING,"Icon","warning");
                status = -3;
                return;
            end

            % =========================================================
            % reset the data in memory
            InitData(app);

            PanelWaiting(app);  %

            app.img_raw = regmov(mov, opts, rt, zeros(1,numel(opts.cOrder)));
            app.img_aligned = regmov.empty();   % reset the movie-aligned

            % init start volume at channel 1
            app.c_order = opts.cOrder;
            app.cidx_cur = 1;

            % initialize registration and view parameters
            app.sidx_cur = 1;
            app.fidx_cur = 1;
            app.roi_viewer_cur.xy = [1, 1; ...
                app.img_raw.MetaData.width, app.img_raw.MetaData.height];
            app.roi_viewer_cur.f = 1:app.img_raw.MetaData.frames;
            app.roi_viewer_cur.z = [1, app.img_raw.MetaData.slices];
            app.frames_reg = ["1:end", "1"];
            app.fps.base = opts.frames/(rt(end)-rt(1)); % real time fps
            app.fps.fold = 1;                           % play speed fold
            app.sbar.update(app.img_raw.MetaData);

            % update player timer
            UpdatePlayerTimer(app, opts.frames, app.fps.base);
            app.viewer_src = "raw";
            app.mode_merge = "none";

            PanelRecovered(app);

            RefreshViewer(app);

            status = 0;
        end

        function Free(app)
            % reset the load flag to avoid bad call back
            app.loaded_flag = false;    

            % stop timers
            RemoveTimer(app);

            % remove ROIs
            delete(app.hdle_uROI);
            ClearMultiplyROIs(app);

            % remove scale bar
            delete(app.sbar);

            % close all sub handles
            CloseAllSubApp(app);

            % unload kernel
            delete(app.kernel);

            % unload external library
            Register.UnloadExternalLib();

            % disconnect the labnas sftp
            close(app.sftp_to_labnas);

            % remove movie resource
            delete(app.img_raw);
            delete(app.img_aligned);

            % recover recycle status
            recycle(app.recycle_old_status);

            % delete this at last
            delete(app);
        end

        function ChangeRunModal(app)
            PanelWaiting(app);
            % this function change the running modal by memmap_flag
            if app.flag_distrib == true
                % change to shared mode
                % load data into memory
                if ~isempty(app.img_raw)
                    app.img_raw.spread();
                end
                if ~isempty(app.img_aligned)
                    app.img_aligned.spread();
                end
            else
                % change to exclusive mode
                % save memory data to disk
                if ~isempty(app.img_raw)
                    app.img_raw.gather();
                end
                if ~isempty(app.img_aligned)
                    app.img_aligned.gather();
                end
            end
            PanelRecovered(app);

            RefreshViewer(app);
        end

        function UpdateCurrentSlice(app, mode, shift)
            arguments
                app
                mode (1,1) string {mustBeMember(mode, ["NOR", "CC"])} ...
                    = "NOR";        % "NOR" for normal view, "CC" for channel correct view 
                shift (1,3) double = [0, 0, 0]; % dx, dy, da
            end
            % This function update the current slice: app.sliceimg_cur for
            % viewing, where the data type is m*n*3 true color uint16 image
            % if loading the data info
            if app.loaded_flag == true
                if mode == "NOR"
                    switch app.mode_merge
                        case "none"
                            pcolor = app.c_order(app.cidx_cur);
                            % no combination, display the channel with
                            % single color, only depends on source
                            if app.mode_zproj == "none"
                                switch app.viewer_src
                                    case "raw"
                                        slice_top = app.img_raw.Movie(:,:, ...
                                            app.cidx_cur, ...
                                            app.sidx_cur, ...
                                            app.fidx_cur);
                                    case "aligned"
                                        slice_top = app.img_aligned.Movie(:,:, ...
                                            app.cidx_cur, ...
                                            app.sidx_cur, ...
                                            app.fidx_cur);
                                    otherwise
                                end
                            else
                                switch app.viewer_src
                                    case "raw"
                                        slice_top = app.img_zproj.raw(:,:,...
                                            app.cidx_cur,app.fidx_cur);
                                    case "aligned"
                                        slice_top = app.img_zproj.aligned(:,:,...
                                            app.cidx_cur,app.fidx_cur);
                                    otherwise
                                end
                            end

                            % generate the view channel use pseudo color
                            slice_top = TransformBCG(slice_top, app.viewer_opts);
                            slice_cur = CompositeC(slice_top, [],...
                                app.mode_merge,1,pcolor);
                        otherwise
                            % merge mode could be mixed channel, which come
                            % from fixed and source channel
                            if app.mode_zproj == "none"
                                slice_top = app.refvol.RefVol(:,:,app.sidx_cur);
                                switch app.viewer_src
                                    case "raw"
                                        slice_bot = app.img_raw.Movie(:,:,app.cidx_cur,...
                                            app.sidx_cur,app.fidx_cur);
                                    case "aligned"
                                        slice_bot = app.img_aligned.Movie(:,:,app.cidx_cur,...
                                            app.sidx_cur,app.fidx_cur);
                                    otherwise
                                end
                            else
                                slice_top = app.img_zproj.ref;
                                switch app.viewer_src
                                    case "raw"
                                        slice_bot = app.img_zproj.raw(:,:,...
                                            app.cidx_cur,app.fidx_cur);
                                    case "aligned"
                                        slice_bot = app.img_zproj.aligned(:,:,...
                                            app.cidx_cur,app.fidx_cur);
                                    otherwise
                                end

                            end

                            slice_top = TransformBCG(slice_top, app.viewer_opts);
                            slice_bot = TransformBCG(slice_bot, app.viewer_opts);
                            slice_cur = CompositeC(slice_top, slice_bot,...
                                app.mode_merge);
                    end
                elseif mode == "CC"
                    % slice_s for signal channel, slice_a for align channel
                    if app.mode_zproj == "none"
                        if ~isempty(app.img_raw)
                            [~, cidx] = ismember(app.opt_reg.Options.FC, ...
                                app.img_raw.MetaData.cOrder);
                            slice_s = app.img_raw.Movie(:,:,cidx,...
                                app.sidx_cur,app.fidx_cur);
                            slice_s = TransformBCG(slice_s, app.viewer_opts);
                            [~, cidx] = ismember(app.opt_reg.Options.SC, ...
                                app.img_raw.MetaData.cOrder);
                            slice_a = app.img_raw.Movie(:,:,cidx,...
                                app.sidx_cur,app.fidx_cur);
                            slice_a = TransformBCG(slice_a, app.viewer_opts);
                        end
                    else
                        if ~isempty(app.img_zproj.raw)
                            [~, cidx] = ismember(app.opt_reg.Options.FC, ...
                                app.img_raw.MetaData.cOrder);
                            slice_s = app.img_zproj.raw(:,:,...
                                cidx,app.fidx_cur);
                            slice_s = TransformBCG(slice_s, app.viewer_opts);
                            [~, cidx] = ismember(app.opt_reg.Options.SC, ...
                                app.img_raw.MetaData.cOrder);
                            slice_a = app.img_zproj.raw(:,:,...
                                cidx,app.fidx_cur);
                            slice_a = TransformBCG(slice_a, app.viewer_opts);
                        end
                    end
                    % do imwarp for slice_s motion
                    rot = [cosd(shift(3)), sind(shift(3)); ...
                           -sind(shift(3)), cosd(shift(3))];
                    RA = imref2d(size(slice_s), app.img_raw.MetaData.xRes, ...
                        app.img_raw.MetaData.yRes);
                    tform = rigid2d(rot, shift(1:2));
                    slice_s_tf = imwarp(slice_s, RA, tform, "linear", ...
                        "FillValues",prctile(slice_s, 5, "all"), ...
                        "OutputView", RA);
                    slice_cur = CompositeC(slice_s_tf,...
                                           slice_a,...
                                           "none",...
                                           1,...
                                           "mix");
                end

                app.simg_cur = slice_cur;
            else
                app.simg_cur = [];
            end
        end

        function UpdateMultiplyROIs(app, dview)
            if app.loaded_flag == true && app.masked_flag ~= "none"
                switch dview
                    case {'XY', 'Z'}
                        % remove all rois(multiply), clear screen
                        ClearMultiplyROIs(app);

                        % append new rois
                        if app.mode_zproj == "none"
                            data = app.mask_reg.MaskROI{app.sidx_cur};

                            if isempty(data), return; end

                            % only 'none' view with roi drawing
                            color = jet(app.mask_reg.NumComps);

                            [~, loc] = ismember(data(:,4), app.mask_reg.MaskComps);
                            app.hdle_mROI = cell(1, size(data, 1));

                            for k = 1:size(data, 1)
                                app.hdle_mROI{k} = ...
                                    images.roi.Circle(app.UIAxes_Slice, ...
                                    "Center", data(k,1:2), ...
                                    "Radius",data(k,3), ...
                                    "Deletable",false, ...
                                    "FaceAlpha", 0.3, ...
                                    "LineWidth", 0.5, ...
                                    "Label", app.mask_reg.MaskLabel(loc(k)), ...
                                    "LabelAlpha", 0.5, ...
                                    "MarkerSize",eps, ...
                                    "UserData",data(k,4), ...   % absolute identity
                                    "LabelVisible","hover", ...
                                    "InteractionsAllowed", "reshape");
                                
                                % if selected
                                if app.mROI_hl(data(k,4)) == true
                                    app.hdle_mROI{k}.Color = color(data(k,4), :);
                                    app.hdle_mROI{k}.LabelVisible = "on";
                                    app.hdle_mROI{k}.Selected = true;
                                    app.hdle_mROI{k}.LabelTextColor = 1-color(data(k,4), :); % three body design
                                else
                                    app.hdle_mROI{k}.Color = [1, 1, 1];
                                    app.hdle_mROI{k}.LabelVisible = "hover";
                                    app.hdle_mROI{k}.Selected = false;
                                    app.hdle_mROI{k}.LabelTextColor = [0, 0, 0];  
                                end
                            end
                        end
                    otherwise
                        % no roi change needed
                end
            end
        end

        function ClearMultiplyROIs(app)
            for k = 1:numel(app.hdle_mROI)
                delete(app.hdle_mROI{k});
            end
            app.hdle_mROI = {};
        end

        function DrawUtilsComponents(app, dview)
            if ~isempty(app.simg_cur)
                % draw information text
                if ~isempty(app.text_time.handle) && isvalid(app.text_time.handle)
                    app.text_time.handle.String = ...
                        app.text_time.text(app.fidx_cur);
                end
                if ~isempty(app.text_meta.handle) && isvalid(app.text_meta.handle)
                    app.text_meta.handle.String = ...
                        app.text_meta.text{app.fidx_cur};
                end

                % draw ROIs(multiply)
                UpdateMultiplyROIs(app, dview);
            end
        end

        function SourceZProjection(app, frame, method)
            % default z projection is max z projection
            aligned_flag = ...
                (~isempty(app.img_aligned) && isvalid(app.img_aligned));

            % setup the z projection method
            if ~exist("method","var") || isempty(method)
                method = "max";
            else
                if method == "none"
                    % no update and quit immediately
                    return;
                end
            end

            app.img_raw.ZProjMethod = method;
            if aligned_flag == true
                % synchronize aligned channel
                app.img_aligned.ZProjMethod = method;
            end

            % call z projection property
            if ~exist("frame","var") || isempty(frame)
                app.img_zproj.raw = app.img_raw.MovieZProj;
                if aligned_flag == true
                    % synchronize aligned channel
                    app.img_zproj.aligned = app.img_aligned.MovieZProj;
                end
            else
                % increasing update
                app.img_zproj.raw(:,:,:,frame) = ...
                    app.img_raw.tcrop(frame).MovieZProj;
                if aligned_flag == true
                    app.img_zproj.aligned(:,:,:,frame) = ...
                        app.img_aligned.tcrop(frame).MovieZProj;
                end
            end

            % update the refvol z projection
            app.img_zproj.ref = Projection(app.refvol.RefVol, method, 3);
        end

        function status = RemoveCameraBKG(app)
            % Note that: 95B on CSU-X1 noise ~ N(100, 2^2)
            status = 0;
            if app.Menu_RemoveBackgroundFix.Checked == "on"
                switch numel(app.img_raw.MetaData.cOrder)
                    case 1
                        % single channel case
                        prompt = {['\fontsize{12} ', ...
                            app.region.chmap.FUNCTIONAL_BACKGROUND.char()]};
                        dlgtitle = app.region.chmap.REMOVE_BACKGROUND.char();
                        dims = [1 40];
                        definput = {'94'};
                        dlgopts.WindowStyle = 'normal';
                        dlgopts.Interpreter = "tex";
                        dlgout = inputdlg(prompt,dlgtitle,dims,definput,dlgopts);
                    case 2
                        % dual channels
                        prompt = {['\fontsize{12} ', ...
                            app.region.chmap.STRUCTURED_BACKGROUND.char()], ...
                            ['\fontsize{12} ', ...
                            app.region.chmap.FUNCTIONAL_BACKGROUND.char()]};
                        dlgtitle = app.region.chmap.REMOVE_BACKGROUND.char();
                        dims = [1 40;1 40];
                        definput = {'94', '94'};
                        dlgopts.WindowStyle = 'normal';
                        dlgopts.Interpreter = "tex";
                        dlgout = inputdlg(prompt,dlgtitle,dims,definput,dlgopts);
                    otherwise
                end

                if isempty(dlgout)
                    status = -1;
                    return;
                else
                    mov_bkg = str2double(dlgout);
                    if any(isnan(mov_bkg)) || any(mov_bkg < 0)
                        status = -1;
                        return;
                    end
                end

                % modify the mov_bkg as channel setting
                switch numel(app.img_raw.MetaData.cOrder)
                    case 1
                    case 2
                        [~, locs] = ...
                            ismember([app.fixdef.Channel], app.img_raw.MetaData.cOrder);
                        locf = setdiff(1:app.img_raw.MetaData.channels, locs);
                        mov_bkg = mov_bkg([locs, locf]);    % to color representation with cOrder
                    otherwise
                end
                
                % remove the background
                app.img_raw.rmbkg(mov_bkg);
                % note that 'raw' and 'aligned' share the same background
                if ~isempty(app.img_aligned)
                    app.img_aligned.rmbkg(mov_bkg);
                end

            elseif app.Menu_RemoveBackgroundAuto.Checked == "on"
                % using the median value of train as the background
                % estimation
                mov_bkg = app.img_raw.EMedian;

                app.img_raw.rmbkg(mov_bkg);
                if ~isempty(app.img_aligned)
                    % synchronize with aligned source
                    app.img_aligned.rmbkg(mov_bkg);
                end
            end
            app.flag_bkg_removed = true;

            GenSysMsg(app, ["REMOVED_BACKGROUND",""]);
        end

        function RecoverCameraBKG(app)
            % recover the background
            app.img_raw.rcbkg();
            if ~isempty(app.img_aligned)
                % synchronize with aligned source
                app.img_aligned.rcbkg();
            end

            app.flag_bkg_removed = false;

            GenSysMsg(app, ["RECOVERED_BACKGROUND",""]);
        end

        function status = UpdateRegion(app, lang)
            if ismember(lang, ["zh_CN","en_US"])
                app.region.lang = lang;
                app.region.chmap = readstruct(['lang',filesep,char(lang),'.xml'], ...
                    "FileType","xml");
                status = 0;
            else
                status = -1;
            end
        end

        function r = RegisterFrameAdapter(app)
            % This adapter translate the regframe recording to kernel recognized type
            switch app.opt_reg.Algorithm
                case "MANREG"
                    r = str2double(app.frames_reg(2));
                otherwise
                    r = app.frames_reg(1).replace("end", ...
                        string(app.img_raw.MetaData.frames));
                    r = str2num(r); %#ok<ST2NM>
            end
        end

        function slice_img = GetSliceImgAt(app, z, t)
            arguments
                app (1,1) Register;
                z = app.sidx_cur;
                t (1,1) = app.fidx_cur;
            end

            if app.mode_zproj == "none"
                switch app.viewer_src
                    case "raw"
                        slice_img = app.img_raw.Movie(:,:,app.cidx_cur,...
                            z, t);
                    case "aligned"
                        slice_img = app.img_aligned.Movie(:,:,app.cidx_cur,...
                            z, t);
                end
            else
                switch app.viewer_src
                    case "raw"
                        slice_img = app.img_zproj.raw(:,:,...
                            app.cidx_cur, t);
                    case "aligned"
                        slice_img = app.img_zproj.aligned(:,:,...
                            app.cidx_cur, t);
                end
            end
        end
        
        %================= USER INTERFSACE OPTIMIZATION ==================
        
        function PanelWaiting(app)
            % set the pointer for better visual effort
            set(app.Reg3DV212WukongUIFigure,'Pointer','watch');
            drawnow
        end

        function PanelRecovered(app)
            % set the pointer back
            % why need user mouse operation trigger?
            set(app.Reg3DV212WukongUIFigure,'Pointer','arrow');
            drawnow
        end

        %==================== SYSTEM PRESET SET/GET ======================
       
        function SavePreset(app,filename)
            % generate a struct contain part of stable variables as preset
            preset_save = app.opt_reg.Options;
            preset_save.alg = app.opt_reg.Algorithm;
            preset_save.mode = app.opt_reg.Mode;
            preset_save.fps_fold = app.fps.fold;
            preset_save.key_mode = app.mode_loop;
            writestruct(preset_save,filename,"FileType","xml");
            GenSysMsg(app, ["CONFIGURATION_EXPORTED",""]);
        end

        function preset_load = LoadPreset(app, filename)
            preset_load = readstruct(filename,"FileType","xml");

            % check the fields
            fields_name = fieldnames(preset_load);
            reg_fieldsname = fieldnames(app.opt_reg.Options);
            fields_apdx = {'fps_fold';'key_mode';'alg';'mode'};
            if all(ismember(fields_name,union(reg_fieldsname,fields_apdx)))...
                    && numel(fields_name) == numel(reg_fieldsname) + numel(fields_apdx)
                % change the algorithm and mode at first
                app.opt_reg.Algorithm = preset_load.alg;
                app.opt_reg.Mode = preset_load.mode;

                % append other parameters
                for k = 1:numel(reg_fieldsname)
                    if strcmp(reg_fieldsname{k}, 'Hardware') == true
                        % skip hardware information(hardware independent)
                        continue;
                    end
                    app.opt_reg = set(app.opt_reg, reg_fieldsname{k}, ...
                        preset_load.(reg_fieldsname{k}));
                end
                
                app.fps.fold = preset_load.fps_fold;
                app.mode_loop = preset_load.key_mode;
                GenSysMsg(app, ["CONFGURATION_LOADED",""]);
            else
                uialert(app.Reg3DV212WukongUIFigure, ...
                    app.region.CONFIGURATION_CORRUPTED,...
                    app.region.ERROR, "Icon","error");
                preset_load = [];
                return;
            end
        end

        %===================== SYSTEM CONTROLLER =========================

        function UpdateSystemAfterLoading(app)
            % update registration options because the algorithm depends on
            % movie channels
            UpdateRegOptionsAfterLoading(app);

            % update panels status
            UpdatePanelsAfterLoading(app);

            % update uiaxes object: slice viewer basic configuration
            InitUIAxes_Slice(app);

            % refresh the slice viewer display
            SetSlider_Slice(app,'on');
            SetSlider_Time(app, [1, app.img_raw.MetaData.frames]);
            RefreshViewer(app);
        end

        function LockComponentsBeforeRegistration(app)
            app.Menu_File.Enable = "off";
            app.Menu_Image.Enable = "off";
            app.Menu_Language.Enable = "off";
            app.Menu_SubmitBug.Enable = "off";
            app.Menu_LoadSetting.Enable = "off";
            app.Menu_SaveSetting.Enable = "off";
            app.Menu_Register.Enable = "off";
            app.Menu_RunModal.Enable = "off";
            app.Spinner_Slice.Enable = "off";
            app.Slider_Slice.Enable = "off";
            app.Spinner_Time.Enable = "off";
            app.Slider_Time.Enable = "off";
            app.DropDown_Source.Enable = "off";
            app.Menu_Horizontal.Enable = "off";
            app.Menu_Vertical.Enable = "off";
            app.Menu_RectangleROI.Enable = "off";
            app.Menu_CircleROI.Enable = "off";
            app.Menu_PolygonROI.Enable = "off";
            app.Menu_FreehandROI.Enable = "off";
            app.Menu_ExportLog.Enable = "off";
            app.Menu_ClearLog.Enable = "off";
            app.TextArea_Log.Enable = "off";
            app.Switch_Log.Enable = "off";
            app.Button_HistoryManager.Enable = "off";
            
            drawnow
        end

        function UnlockComponentsAfterRegistration(app)
            app.Menu_File.Enable = "on";
            app.Menu_Image.Enable = "on";
            app.Menu_Language.Enable = "on";
            app.Menu_SubmitBug.Enable = "on";
            app.Menu_LoadSetting.Enable = "on";
            app.Menu_SaveSetting.Enable = "on";
            app.Menu_Register.Enable = "on";
            app.Menu_RunModal.Enable = "on";
            if app.mode_zproj == "none"
                app.Spinner_Slice.Enable = "on";
                app.Slider_Slice.Enable = "on";
            end
            app.Spinner_Time.Enable = "on";
            app.Slider_Time.Enable = "on";
            app.DropDown_Source.Enable = "on";
            app.Menu_Horizontal.Enable = "on";
            app.Menu_Vertical.Enable = "on";
            app.Menu_RectangleROI.Enable = "on";
            app.Menu_CircleROI.Enable = "on";
            app.Menu_PolygonROI.Enable = "on";
            app.Menu_FreehandROI.Enable = "on";
            app.Menu_ExportLog.Enable = "on";
            app.Menu_ClearLog.Enable = "on";
            app.TextArea_Log.Enable = "on";
            app.Switch_Log.Enable = "on";
            app.Button_HistoryManager.Enable = "on";

            drawnow
        end

        function CloseSubApp(app, h_name) %#ok<INUSD>
            h = eval("app."+h_name+"_handle;");

            if ~isempty(h) && isvalid(h)
                h.Close();
            end
        end

        function CloseAllSubApp(app)
            for name = app.SUBAPP_SNAME
                CloseSubApp(app, name);
            end
        end

        function CloseDataRelatedSubApp(app)
            SUBAPP_RNAME = ["BC","CC","EV","FS","HM","ME","MG","RS","SB","SE","TV"];
            for name = SUBAPP_RNAME
                CloseSubApp(app, name);
            end
        end

        function RefreshSubAppLanguage(app, h_name) %#ok<INUSD>
            h = eval("app."+h_name+"_handle;");

            if ~isempty(h) && isvalid(h)
                h.RefreshPanelLanguage();
            end
        end

        function RefreshAllSubAppLanguage(app)
            for name = app.SUBAPP_SNAME
                RefreshSubAppLanguage(app, name);
            end
        end

        function RegisterGo(app)
            % update button status
            app.Button_Run.BackgroundColor = [0.4, 1, 0];
            app.Button_Run.Text = app.region.chmap.IN_PROGRESS;
            drawnow

            % update controller arguments
            app.kernel.RegisterOptions = app.opt_reg;
            app.kernel.Distributed = app.flag_distrib;
            regfr = RegisterFrameAdapter(app);

            % Lock Panels
            LockComponentsBeforeRegistration(app);

            switch app.viewer_src
                case "raw"
                    if isempty(app.img_aligned)
                        app.img_aligned = app.img_raw.copy();
                    end
                    % update template
                    app.refvol = regtmpl(app.img_raw, app.fixdef);
                    app.kernel.run(app.img_raw, ...
                        app.img_aligned, ...
                        app.refvol, ...
                        regfr);
                case "aligned"
                    % update template
                    app.refvol = regtmpl(app.img_aligned, app.fixdef);
                    app.kernel.run(app.img_aligned, ...
                        app.img_aligned, ...
                        app.refvol, ...
                        regfr);
                otherwise
            end

            % append the merge menu
            if isempty(app.Menu_MergeSource) ...
                    || ~isvalid(app.Menu_MergeSource)
                AppendMergeChannelMenu(app);
            end

            app.img_operators = setdiff(union(app.img_operators, "aligned"), "none");
            % foolproof point
            app.DropDown_Source.Items = [app.region.chmap.RAW, ...
                app.region.chmap.ALIGNED];
            app.DropDown_Source.Value = "ALIGNED";
            app.viewer_src = "aligned";

            % Synchronize z projection
            SourceZProjection(app, [], app.mode_zproj);

            RefreshViewer(app);

            % update main panel appearance
            app.Button_Run.Value = false;
            app.Button_Run.Text = app.region.chmap.RUN;
            app.Button_Run.BackgroundColor = [0.96,0.96,0.96];

            % unlock options
            app.Menu_RegEvaluate.Enable = "on";
            app.Button_HistoryManager.Enable = "on";
            UnlockComponentsAfterRegistration(app);
        end

        function RegNotReadyWarn(app)
            if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                switch numel(app.c_order)
                    case 1
                        app.RS_handle.FontBlink("CheckBox_Lock_" + ...
                            ["Global","Local","MGlobal"]);
                    case 2
                        app.RS_handle.FontBlink("CheckBox_Lock_" + ...
                            ["Global","Local","MGlobal","LTGlobal"]);
                    otherwise
                end
            end
            app.Button_Run.Value = false;
            GenSysMsg(app, ["REGISTRATION_SOLUTION_ERROR",""]);
        end

        function RemoveTimer(app)
            if ~isempty(app.player_timer)
                stop(app.player_timer);
                delete(app.player_timer);
            end
            if ~isempty(app.progress_timer)
                stop(app.progress_timer);
                delete(app.progress_timer);
            end
        end

        function status = GenSysMsg(app, cmdstrs, out, timestamp)
            arguments
                app         (1,1)   Register
                cmdstrs     (1,2)   string
                out         (1,1)   logical     = true
                timestamp   (1,1)   logical     = true
            end

            if app.Switch_Log.Value == "off"
                status = size(app.sysmsg, 1);
                return;
            end

            % record the message
            if timestamp == true
                app.sysmsg = [app.sysmsg; ...
                    cmdstrs(1), cmdstrs(2), string(datetime("now"))];
            else
                app.sysmsg = [app.sysmsg; ...
                    cmdstrs(1), cmdstrs(2), ""];
            end

            % apply output
            if out == true
                t_msg = AppendTextArea_Log(app, ...
                    app.region.chmap.(cmdstrs(1))+cmdstrs(2),...
                    timestamp);
                app.sysmsg(end, 3) = string(t_msg);
            end

            status = size(app.sysmsg, 1);
        end

        function ValidateUpdate(app)
            % This function read the update profile and set options
            fid = fopen(['reg3d_startup_plugins', filesep, ...
                'update_setting.ini'], "r");

            if fid == -1
                uialert(app.Reg3DV212WukongUIFigure,...
                    app.region.chmap.UNABLE_ACCESS_UPDATE_SETTING,...
                    app.region.chmap.WARNING, "Icon","warning");
                return;
            end
            setting_profile = textscan(fid, "%s",'Delimiter','\n');
            fclose(fid);
            setting_profile = string(setting_profile{1});

            for k = 1:numel(setting_profile)
                if setting_profile(k).replace(" ","") == "" ...
                        || iscomment(setting_profile(k))
                    % skip this line
                    continue;
                end
                key = setting_profile(k).extractBefore(" = ");

                switch upper(key)
                    case "REPOSITORY_PC"
                        if ispc()
                            value = setting_profile(k).extractAfter(" = ");
                            if isfolder(value)
                                app.update_opts.repository = value;
                            else
                                warning(app.region.chmap.INVALID_UPDATE_REPOSITORY);
                            end
                        end
                    case "REPOSITORY_UNIX"
                        if isunix()
                            value = setting_profile(k).extractAfter(" = ");
                            if isfolder(value)
                                app.update_opts.repository = value;
                            else
                                warning(app.region.chmap.INVALID_UPDATE_REPOSITORY);
                            end
                        end
                    case "AUTO_CLEAN"
                        value = lower(setting_profile(k).extractAfter(" = "));
                        if value == "0" || value == "off" || value == "false"
                            app.update_opts.auto_clean = false;
                        elseif value == "1" || value == "on" || value == "true"
                            app.update_opts.auto_clean= true;
                        else
                            warning(app.region.chmap.UNRECOGNIZED_UPDATE_IDENTIFIER);
                        end
                    otherwise
                        if ~ismissing(key)
                            warning(app.region.chmap.UNRECOGNIZED_UPDATE_IDENTIFIER);
                        end
                end
            end

            % set the update image visiable
            [status, ~, ~, ch] = acquire_update(app.update_opts.repository, Register.VER);

            switch status
                case "UPDATABLE"
                    pathToMLAPP = fileparts(mfilename('fullpath'));
                    switch upper(ch)
                        case "PREVIEW"
                            app.Image_Update.ImageSource = ...
                                fullfile(pathToMLAPP, 'sources', 'update_preview.png');
                        case "RELEASE"
                            app.Image_Update.ImageSource = ...
                                fullfile(pathToMLAPP, 'sources', 'update_release.png');
                        case "DEBUG"
                            app.Image_Update.ImageSource = ...
                                fullfile(pathToMLAPP, 'sources', 'bug.png');
                        otherwise
                    end
                    app.Image_Update.Visible = "on";
                case "ACCESS_FAILED"
                    app.Image_Update.Visible = "off";
                    uialert(app.Reg3DV212WukongUIFigure,...
                        app.region.chmap.UNABLE_ACCESS_SERVER, ...
                        app.region.chmap.ERROR, "Icon","error");
                    return;
                otherwise
                    app.Image_Update.Visible = "off";
            end

            function r = iscomment(str, marker)
                if ~exist("marker", "var")
                    marker = "#";
                end
                % comment means the marker is header
                r = (string(str).extractBefore(marker).replace(" ","") == "");
            end
        end

        function HoldWindowOn(app)
            % Use this function after uigetfile/uiputfile/uigetdir/uiopen
            % ... file operations, etc
            app.Reg3DV212WukongUIFigure.WindowStyle = "alwaysontop";
            pause(0.5);
            app.Reg3DV212WukongUIFigure.WindowStyle = "normal";
        end

        %================== SELF DEFINATION CALL BACK ====================
       
        function ROI_Talking(app,src,evt)
            evname = evt.EventName;
            switch(evname)
                case{'MovingROI'}
                    R = ROI2Rectangle(src);
                    % convert coordinate to pixel size
                    [~,W_pix,H_pix] = Coordinate2Pixel(R,...
                        diff(app.UIAxes_Slice.XLim)/app.img_raw.MetaData.width,...
                        diff(app.UIAxes_Slice.YLim)/app.img_raw.MetaData.height);
                    UpdateUIAxes_Slice_Title(app,[],[],[W_pix,H_pix]);
                case{'DeletingROI'}
                    UpdateUIAxes_Slice_Title(app);
                otherwise
            end
        end

        function TimeText_ButtonDownFcn(app, event)
            switch event.Button
                case 1
                    % left button for motion tracking
                    % switch the moving status
                    app.text_time.ismoving = ~app.text_time.ismoving;
                case 2
                    % middle button for font setting
                    uisetfont(app.text_time.handle);
                case 3
                    % right button for color setting
                    uisetcolor(app.text_time.handle);
                otherwise
            end
        end

        function MetaText_ButtonDownFcn(app, event)
            switch event.Button
                case 1
                    % left button for motion tracking
                    % switch the moving status
                    app.text_meta.ismoving = ~app.text_meta.ismoving;
                case 2
                    uisetfont(app.text_meta.handle);
                case 3
                    uisetcolor(app.text_meta.handle);
                otherwise
            end
        end

        function Menu_MergeNoneSelected(app, event)
            event; %#ok<VUNUS> 

            app.mode_merge = "none";
            app.Menu_MergeNone.Checked = "on";
            app.Menu_MergeRB.Checked = "off";
            app.Menu_MergeGR.Checked = "off";

            app.DropDown_Source.Enable = "on";

            RefreshViewer(app);
        end

        function Menu_MergeRBSelected(app, event)
            event; %#ok<VUNUS> 

            app.mode_merge = "red-cyan";
            app.Menu_MergeNone.Checked = "off";
            app.Menu_MergeRB.Checked = "on";
            app.Menu_MergeGR.Checked = "off";

            app.DropDown_Source.Enable = "off";

            RefreshViewer(app);
        end

        function Menu_MergeGRSelected(app, event)
            event; %#ok<VUNUS> 

            app.mode_merge = "green-magenta";
            app.Menu_MergeNone.Checked = "off";
            app.Menu_MergeRB.Checked = "off";
            app.Menu_MergeGR.Checked = "on";

            app.DropDown_Source.Enable = "off";

            RefreshViewer(app);
        end

    end

    %========================== CLASS METHODS ============================
    
    methods(Static)
        function status = DeployParallelConfig()
            % load the parallel setting on different platform
            % note that app will not recover the configuration
            pinfo = struct2table(ver);
            par_flag = any(string(pinfo.Name).contains(...
                "Parallel Computing Toolbox"));

            if par_flag == true
                profs = string(parallel.listProfiles);  % >=R2022b
                if ismember("Reg3D_Local", profs) ...
                        || ismember("Reg3D_Server", profs)
                    if ismember("Reg3D_Local", profs) ...
                        && ismember("Reg3D_Server", profs)
                        error("Reg3D:invalidParallelConfiguration", ...
                            "Please keep only one parallel profile in " + ...
                            "'Cluster Profile Manager' manually.");
                    end
                    
                    if ismember("Reg3D_Local", profs) ...
                            && (parallel.defaultProfile()~="Reg3D_Local")
                        fprintf("Parallel profile changed: %s -> %s\n", ...
                            parallel.defaultProfile(), "Reg3D_Local");
                        parallel.defaultProfile("Reg3D_Local");
                    elseif ismember("Reg3D_Server", profs) ...
                            && (parallel.defaultProfile()~="Reg3D_Server")
                        fprintf("Parallel profile changed: %s -> %s\n", ...
                            parallel.defaultProfile(), "Reg3D_Server");
                        parallel.defaultProfile("Reg3D_Server");
                    end
                    status = 0;
                else
                    % get platform information and select the better
                    % configuration
                    pathToMLAPP = fileparts(mfilename('fullpath'));
                    local_setting_path = [pathToMLAPP, filesep, 'bin',filesep, ...
                        'Reg3D_Local.mlsettings'];
                    server_setting_path = [pathToMLAPP, filesep, 'bin',filesep, ...
                        'Reg3D_Server.mlsettings'];
                    disp("Auto deploy the parallel pool configuration at first use...");
                    sinfo = getCpuInfo();
                    if sinfo.NumProcessors > 16
                        if sinfo.OSType == "Linux"
                            disp("Reg3D_Server profile is enabled...");
                            % this machine is linux server
                            pf = parallel.importProfile(server_setting_path);
                            parallel.defaultProfile(pf); % set as default
                        else
                            % unknown work station
                            warning("Reg3D:unknownHPC",...
                                "Unknown HPC. Reg3D_Local profile enabled. " + ...
                                "You can modify it manually.");
                            pf = parallel.importProfile(local_setting_path);
                            parallel.defaultProfile(pf); % set as default
                        end
                    else
                        % no matter local machine OS
                        disp("Reg3D_Local profile is enabled...");
                        pf = parallel.importProfile(local_setting_path);
                        parallel.defaultProfile(pf); % set as default
                    end
                    disp("Done.");
                    status = 0;
                end
            else
                status = -1;
            end
        end

        function status = UnloadExternalLib()
            % all external calling must be included in try...catch...
            try
                if libisloaded('Nd2ReadSdk')
                    unloadlibrary('Nd2ReadSdk');
                end
            catch ME
                switch ME.identifier
                    case ""
                    otherwise
                end
            end

            status = 0;
        end

        function r = GetAppPath()
            r = fileparts(mfilename("fullpath"));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mode)
            % note that mode can be "release" and "debug"
            % where "release" mode as default
            
            Register.DeployParallelConfig();

            DeployEnvConfig(app);
            InitData(app);
            InitTimer(app);
            SetSystemLanguage(app);
            InitPanels(app);
            ValidateUpdate(app);

            GenSysMsg(app, ["SYS_INITIALIZE_SUCCESS",""]);
        end

        % Menu selected function: Menu_LoadFile
        function Menu_LoadFileSelected(app, event)
            imreader = ImageReader(app, app.flag_distrib);

            app.Reg3DV212WukongUIFigure.Resize = "off";

            PanelWaiting(app);
            
            imreader.load("");

            PanelRecovered(app);

            RefreshViewer(app);

            app.Reg3DV212WukongUIFigure.Resize = "on";

            % close all data related panel to avoid unsynchronize view
            CloseDataRelatedSubApp(app);

            opts = imreader.MetaData;
            rt = imreader.T;
            mov = imreader.Data;
            
            % load variables to app workspace
            status = DataConstructor(app, mov, opts, rt);
            if status ~= 0
                return;
            end

            app.filename = imreader.FileName;
            app.loaded_flag = true;
           
            % update the system exterior
            UpdateSystemAfterLoading(app);
        end

        % Menu selected function: Menu_SaveImageAs
        function Menu_SaveImageAsSelected(app, event)
            if ~isempty(app.filename) && exist(app.filename,"file")
                [~, fname, ~] = fileparts(app.filename);
            else
                fname = "processing";
            end
            if any(app.img_operators~="none")
                fname = fname + "_" + app.img_operators(app.img_operators~="none").join("-");
            end

            c_cur = app.c_order(app.cidx_cur);

            if (numel(app.c_order) == 2) && (c_cur == app.opt_reg.Options.SC)
                ftype = "structured";
            else
                ftype = "functional";
            end

            app.Reg3DV212WukongUIFigure.Resize = "off";
            PanelWaiting(app);

            switch app.viewer_src
                case "raw"
                    fname_export = fname+"_"+ftype;
                    operation_save = app.img_operators(app.img_operators~="aligned").join("-");
                    meta_data = struct("ftype",ftype, ...
                                       "operation",operation_save);

                    imwriter = ImageWriter(app, fname_export);
                    imwriter.save(app.img_raw, c_cur, meta_data);
                case "aligned"
                    fname_export = fname+"_aligned_"+ftype;
                    operation_save = app.img_operators.join("-");
                    meta_data = struct("ftype",ftype, ...
                                       "operation",operation_save);

                    imwriter = ImageWriter(app, fname_export);
                    imwriter.save(app.img_aligned, c_cur, meta_data);
                otherwise
            end

            if imwriter.Status == 0
                app.path_save = imwriter.Path;
                GenSysMsg(app, ["IMAGE_EXPORT_SUCCESS",""]);
            else
                app.path_save = "";
            end

            PanelRecovered(app);

            RefreshViewer(app);

            app.Reg3DV212WukongUIFigure.Resize = "on";
        end

        % Menu selected function: Menu_CropT
        function Menu_CropTSelected(app, event)
            selection = uiconfirm(app.Reg3DV212WukongUIFigure,...
                app.region.chmap.IRREVERSIBLE_CROP,...
                app.region.chmap.WARNING, "Icon","warning");
            if selection == "OK"
                % record old frames selected
                f_old = app.roi_viewer_cur.f;

                % call the GUI version at first
                if isempty(app.FS_handle) || ~isvalid(app.FS_handle)
                    app.FS_handle = FrameSelecter(app);
                    waitfor(app.FS_handle);
                end

                if numel(f_old)==numel(app.roi_viewer_cur.f) ...
                        && all(f_old == app.roi_viewer_cur.f)
                    % no change, show the old version selection
                    prompt = {['\fontsize{12} ', app.region.chmap.TIME_RANGE.char()]};
                    dlgtitle = app.region.chmap.CROP_FRAME.char();
                    dims = [1 40];
                    definput = {'1:end'};
                    dlgopts.WindowStyle = 'normal';
                    dlgopts.Interpreter = "tex";
                    dlgout = inputdlg(prompt,dlgtitle,dims,definput,dlgopts);
                    if isempty(dlgout)
                        return;
                    else
                        dlgout=dlgout{1};
                    end
                    dlgout = string(dlgout).replace("end",num2str(app.img_raw.MetaData.frames));
                    app.roi_viewer_cur.f = str2num(dlgout); %#ok<ST2NM>
                end

                % validate selected frames
                if numel(app.roi_viewer_cur.f) < 2
                    uialert(app.Reg3DV212WukongUIFigure, ...
                        app.region.chmap.FRAME_FEW, ...
                        app.region.chmap.WARNING, "Icon","warning");
                    return;
                elseif numel(app.roi_viewer_cur.f) > app.img_raw.MetaData.frames
                    uialert(app.Reg3DV212WukongUIFigure, ...
                        app.region.chmap.FRAME_OVERFLOW, ...
                        app.region.chmap.WARNING, "Icon","warning");
                    return;
                elseif numel(app.roi_viewer_cur.f) == app.img_raw.MetaData.frames
                    uialert(app.Reg3DV212WukongUIFigure, ...
                        app.region.chmap.GIVEUP_CROP,...
                        app.region.chmap.INFORMATION, "Icon","info");
                    return;
                end

                PanelWaiting(app);  %

                % Crop the source movie and time
                if ~isempty(app.img_raw)
                    app.img_raw = app.img_raw.tcrop(app.roi_viewer_cur.f);
                end

                % Crop the aligned movie if it's possible
                % for aligned info
                if ~isempty(app.img_aligned)
                    app.img_aligned = app.img_aligned.tcrop(app.roi_viewer_cur.f);
                end

                % for recovery point
                if ~isempty(app.mdrp.raw)
                    [app.mdrp.raw, ~] = CropT(app.mdrp.raw,...
                            app.roi_viewer_cur.f);
                end
                if ~isempty(app.mdrp.aligned)
                    [app.mdrp.aligned, ~] = CropT(app.mdrp.aligned,...
                            app.roi_viewer_cur.f);
                end

                % crop text vector if is needed
                if ~isempty(app.text_time.text)
                    app.text_time.text = app.text_time.text(app.roi_viewer_cur.f);
                end
                if ~isempty(app.text_meta.text)
                    app.text_meta.text = app.text_meta.text(app.roi_viewer_cur.f);
                end

                % crop the z-projection
                if ~isempty(app.img_zproj.raw)
                    [app.img_zproj.raw, ~] = CropT(...
                        app.img_zproj.raw, app.roi_viewer_cur.f);
                end
                if ~isempty(app.img_zproj.aligned)
                    [app.img_zproj.aligned, ~] = CropT(...
                        app.img_zproj.aligned, app.roi_viewer_cur.f);
                end

                % reorder time axis
                app.fidx_cur = 1;
                SetSlider_Time(app, [1, app.img_raw.MetaData.frames]);

                % Synchronize z projection
                SourceZProjection(app, [], app.mode_zproj);

                % Synchronize registration frames
                app.frames_reg = ["1:end", "1"];
                GenSysMsg(app, ["RESET_REGISTRATION_FRAMES",""]);

                % Synchronize template frames
                tmpl = str2num(app.fixdef.Sampling(2)); %#ok<ST2NM>
                tmpl(~ismember(tmpl, app.roi_viewer_cur.f)) = [];
                if isempty(tmpl)
                    app.fixdef.Sampling(2) = "1";
                else
                    for k = 1:numel(tmpl)
                        tmpl(k) = tmpl(k) - ...
                            numel(setdiff(1:tmpl(k), app.roi_viewer_cur.f));
                    end
                    app.fixdef.Sampling(2) = string(tmpl).join(",");
                end
                if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                    app.RS_handle.Refresh(app);
                end

                % update template
                switch app.viewer_src
                    case "raw"
                        app.refvol = regtmpl(app.img_raw, app.fixdef);
                    case "aligned"
                        app.refvol = regtmpl(app.img_aligned, app.fixdef);
                    otherwise
                end

                app.img_operators = setdiff(union(app.img_operators, "cut"),"none");
                GenSysMsg(app, ["T_CROPPED",""]);

                PanelRecovered(app);    %

                RefreshViewer(app);
            else
                return;
            end
        end

        % Menu selected function: Menu_About
        function Menu_AboutSelected(app, event)
            msg = sprintf(['Register3D Graphics User Interface (RGUI) \n' ...
                'Full function volume series Register \n' ...
                'Version: 2.1.2(Wukong) debug @si-lab \n' ...
                'Author: Weihan Li \n' ...
                'E-mail: liweihan20@mails.ucas.ac.cn']);
            uialert(app.Reg3DV212WukongUIFigure, msg, ...
                app.region.chmap.INFORMATION, "Icon","info");
        end

        % Menu selected function: Menu_Documents
        function Menu_DocumentsSelected(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            help_file = [pathToMLAPP, filesep, 'help', filesep, '0.html'];
            
            if ~exist(help_file, "file")
                uialert(app.Reg3DV212WukongUIFigure, ...
                    app.region.chmap.MISSING_HELP, ...
                    app.region.chmap.ERROR, "Icon","error");
                return;
            else
                web(help_file);
            end
        end

        % Value changed function: Slider_Slice
        function Slider_SliceValueChanged(app, event)
            value = round(app.Slider_Slice.Value);
            app.Slider_Slice.Value = value;

            % Synchronize the slice spinner
            app.Spinner_Slice.Value = value;
            app.sidx_cur = value;

            RefreshViewer(app, "Z");
        end

        % Value changed function: Slider_Time
        function Slider_TimeValueChanged(app, event)
            value = round(app.Slider_Time.Value);
            app.Slider_Time.Value = value;

            % Synchronize the time spinner
            app.Spinner_Time.Value = value;
            app.fidx_cur = value;
            app.frames_reg(2) = string(value);

            RefreshViewer(app, "T");
        end

        % Value changed function: Spinner_Slice
        function Spinner_SliceValueChanged(app, event)
            value = round(app.Spinner_Slice.Value);
            app.Spinner_Slice.Value = value;

            % Synchronize the slice slider
            app.Slider_Slice.Value = value;
            app.sidx_cur = value;
            
            RefreshViewer(app, "Z");
        end

        % Callback function
        function Menu_RedSelected(app, event)
            [~, app.cidx_cur] = ismember("r",app.c_order);

            if numel(app.c_order) == 2
                app.Menu_Red.Checked = "on";
                app.Menu_Green.Checked = "off";

                RefreshViewer(app);
            end

        end

        % Callback function
        function Menu_GreenSelected(app, event)
            [~, app.cidx_cur] = ismember("g",app.c_order);

            if numel(app.c_order) == 2
                app.Menu_Red.Checked = "off";
                app.Menu_Green.Checked = "on";

                RefreshViewer(app);
            end

        end

        % Value changed function: DropDown_Source
        function DropDown_SourceValueChanged(app, event)
            value = app.DropDown_Source.Value;
            switch value
                case "RAW"
                    app.viewer_src = "raw";
                    if app.mode_zproj ~= "none"
                        if isempty(app.img_zproj.raw)
                            SourceZProjection(app);
                        else
                            SourceZProjection(app, app.fidx_cur);
                        end
                    end
                case "ALIGNED"
                    app.viewer_src = "aligned";
                    if app.mode_zproj ~= "none"
                        if isempty(app.img_zproj.aligned)
                            SourceZProjection(app);
                        else
                            SourceZProjection(app, app.fidx_cur);
                        end
                    end
                otherwise
            end

            RefreshViewer(app);
        end

        % Value changed function: Spinner_Time
        function Spinner_TimeValueChanged(app, event)
            value = round(app.Spinner_Time.Value);
            app.Spinner_Time.Value = value;

            % Synchronize the slice slider
            app.Slider_Time.Value = value;
            app.fidx_cur = value;
            app.frames_reg(2) = string(value);

            RefreshViewer(app, "T");
        end

        % Menu selected function: Menu_RectangleROI
        function Menu_RectangleROISelected(app, event)
            if ~isempty(app.hdle_uROI) && isvalid(app.hdle_uROI)
                delete(app.hdle_uROI);
            end
            app.hdle_uROI = drawrectangle(app.UIAxes_Slice);
            addlistener(app.hdle_uROI,'MovingROI',...
                @(src,evt)ROI_Talking(app,src,evt));
            addlistener(app.hdle_uROI,'DeletingROI',...
                @(src,evt)ROI_Talking(app,src,evt));
        end

        % Menu selected function: Menu_CircleROI
        function Menu_CircleROISelected(app, event)
            if ~isempty(app.hdle_uROI) && isvalid(app.hdle_uROI)
                delete(app.hdle_uROI);
            end
            app.hdle_uROI = drawcircle(app.UIAxes_Slice);
            addlistener(app.hdle_uROI,'MovingROI',...
                @(src,evt)ROI_Talking(app,src,evt));
            addlistener(app.hdle_uROI,'DeletingROI',...
                @(src,evt)ROI_Talking(app,src,evt));
        end

        % Menu selected function: Menu_PolygonROI
        function Menu_PolygonROISelected(app, event)
            if ~isempty(app.hdle_uROI) && isvalid(app.hdle_uROI)
                delete(app.hdle_uROI);
            end
            app.hdle_uROI = drawpolygon(app.UIAxes_Slice);
            addlistener(app.hdle_uROI,'MovingROI',...
                @(src,evt)ROI_Talking(app,src,evt));
            addlistener(app.hdle_uROI,'DeletingROI',...
                @(src,evt)ROI_Talking(app,src,evt));
        end

        % Menu selected function: Menu_FreehandROI
        function Menu_FreehandROISelected(app, event)
            if ~isempty(app.hdle_uROI) && isvalid(app.hdle_uROI)
                delete(app.hdle_uROI);
            end
            app.hdle_uROI = drawfreehand(app.UIAxes_Slice);
            addlistener(app.hdle_uROI,'MovingROI',...
                @(src,evt)ROI_Talking(app,src,evt));
            addlistener(app.hdle_uROI,'DeletingROI',...
                @(src,evt)ROI_Talking(app,src,evt));
        end

        % Menu selected function: Menu_SaveSetting
        function Menu_SaveSettingSelected(app, event)
            t = datetime('now','TimeZone','local','Format','yyMMdd');

            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));

            [name,path] = uiputfile({'*.reg3d', ...
                [app.region.chmap.REG3D_CONFIGURATION.char(), '(*.reg3d)']},...
                    app.region.chmap.SAVE_CONFIGURATION_FILE.char(),...
                    [pathToMLAPP, filesep, 'bin', filesep, char(t), '.reg3d']);
            if ~isequal(name,0)
                file_name = fullfile(path,name);
                SavePreset(app,file_name);
            end
        end

        % Menu selected function: Menu_LoadSetting
        function Menu_LoadSettingSelected(app, event)
            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));

            [name,path] = uigetfile({'*.reg3d', ...
                [app.region.chmap.REG3D_CONFIGURATION.char(), '(*.reg3d)']},...
                    app.region.chmap.READ_CONFIGURATION_FILE.char(),...
                    [pathToMLAPP, filesep, 'bin']);
            HoldWindowOn(app);

            if ~isequal(name,0)
                file_name = fullfile(path,name);
                preset = LoadPreset(app, file_name);
                if ~isempty(preset)
                    if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                        % refresh the RS_handle
                        app.RS_handle.Refresh(app);
                    end
                    % update playing menu setting
                    app.fps.fold = preset.fps_fold;
                    app.Menu_1F.Checked = "off";app.Menu_5F.Checked = "off";
                    app.Menu_25F.Checked = "off";
                    switch app.fps.fold
                        case 1
                            app.Menu_1F.Checked = "on";
                        case 5
                            app.Menu_5F.Checked = "on";
                        case 25
                            app.Menu_25F.Checked = "on";
                    end
                    app.mode_loop = preset.key_mode;
                    app.Menu_Direct.Checked = "off";app.Menu_Loop.Checked = "off";
                    switch app.mode_loop
                        case "one-step"
                            app.Menu_Direct.Checked = "on";
                        case "loop"
                            app.Menu_Loop.Checked = "on";
                    end
                end
            end 
        end

        % Menu selected function: Menu_Horizontal
        function Menu_HorizontalSelected(app, event)
            app.Menu_Horizontal.Checked = ~app.Menu_Horizontal.Checked;

            PanelWaiting(app);

            switch app.viewer_src
                case "raw"
                    app.img_raw.fliplr();
                    % Synchronize z projection
                    if app.mode_zproj ~= "none"
                        app.img_zproj.raw = fliplr(app.img_zproj.raw);
                    end
                case "aligned"
                    app.img_aligned.fliplr();
                    if app.mode_zproj ~= "none"
                        app.img_zproj.aligned = fliplr(app.img_zproj.aligned);
                    end
                otherwise
            end

            app.img_zproj.ref = fliplr(app.img_zproj.ref);

            PanelRecovered(app);
                
            RefreshViewer(app);
        end

        % Menu selected function: Menu_Vertical
        function Menu_VerticalSelected(app, event)
            app.Menu_Vertical.Checked = ~app.Menu_Vertical.Checked;
            
            PanelWaiting(app);

            switch app.viewer_src
                case "raw"
                    app.img_raw.flipud();
                    % Synchronize z projection
                    if app.mode_zproj ~= "none"
                        app.img_zproj.raw = flipud(app.img_zproj.raw);
                    end
                case "aligned"
                    app.img_aligned.flipud();
                    if app.mode_zproj ~= "none"
                        app.img_zproj.aligned = flipud(app.img_zproj.aligned);
                    end
            end

            app.img_zproj.ref = flipud(app.img_zproj.ref);

            PanelRecovered(app);

            RefreshViewer(app);
        end

        % Menu selected function: Menu_1F
        function Menu_1FSelected(app, event)
            app.fps.fold = 1;
            app.player_timer.Period = 1/(app.fps.base*app.fps.fold);
            app.Menu_1F.Checked = "on";
            app.Menu_5F.Checked = "off";
            app.Menu_25F.Checked = "off";
        end

        % Menu selected function: Menu_5F
        function Menu_5FSelected(app, event)
            app.fps.fold = 5;
            app.player_timer.Period = 1/(app.fps.base*app.fps.fold);
            app.Menu_1F.Checked = "off";
            app.Menu_5F.Checked = "on";
            app.Menu_25F.Checked = "off";
        end

        % Menu selected function: Menu_25F
        function Menu_25FSelected(app, event)
            app.fps.fold = 25;
            app.player_timer.Period = 1/(app.fps.base*app.fps.fold);
            app.Menu_1F.Checked = "off";
            app.Menu_5F.Checked = "off";
            app.Menu_25F.Checked = "on";
        end

        % Key press function: Reg3DV212WukongUIFigure
        function Reg3DV212WukongUIFigureKeyPress(app, event)
            key = event.Key;            % base key
            modifier = event.Modifier;  % modifier, can be any combination 
                                        % comes from ["control","alt","shift"] 

            if ~isempty(modifier) && all(modifier=="control")
                switch key
                    case {"a","A"}
                        if ~isempty(app.FS_handle) && isvalid(app.FS_handle)
                            app.FS_handle.AppendFrame(app.fidx_cur);
                        end
                    case "tab"
                        if ~isempty(app.img_aligned)
                            if app.mode_merge == "none"
                                app.viewer_src = setdiff(["raw","aligned"], app.viewer_src);
                                switch app.viewer_src
                                    case "raw"
                                        app.DropDown_Source.Value = "RAW";
                                    case "aligned"
                                        app.DropDown_Source.Value = "ALIGNED";
                                end
                                RefreshViewer(app, "T");
                            end
                        end
                    otherwise
                end
            elseif isempty(modifier)
                switch key
                    case {"d","D","rightarrow"}
                        NextFramePtr(app);
                        RefreshViewer(app, "T");
                    case {"a","A","leftarrow"}
                        PrevFramePtr(app);
                        RefreshViewer(app, "T");
                    case {"w","W","uparrow"}
                        NextSlicePtr(app);
                        RefreshViewer(app, "Z");
                    case {"s","S","downarrow"}
                        PrevSlicePtr(app);
                        RefreshViewer(app, "Z");
                    case "space"
                        if app.flag_playing
                            stop(app.player_timer);
                            app.flag_playing = false;
                        else
                            LoopN = app.Slider_Time.Limits(end) - app.fidx_cur;
                            switch app.mode_loop
                                case "one-step"
                                    if isempty(LoopN) || (LoopN==0)
                                        % reject start timer
                                        return;
                                    end
                                case "loop"
                                    LoopN = Inf;
                            end

                            app.player_timer.TasksToExecute = LoopN;
                            LockPlaySetting(app);

                            app.flag_playing = true;
                            start(app.player_timer);
                        end
                        RefreshViewer(app, "T");
                    otherwise
                end
            end
        end

        % Menu selected function: Menu_Direct
        function Menu_DirectSelected(app, event)
            app.Menu_Direct.Checked = true;
            app.Menu_Loop.Checked = false;
            app.mode_loop = "one-step";
            app.player_timer.TasksToExecute = app.Slider_Time.Limits(end) ...
                - app.fidx_cur;
        end

        % Menu selected function: Menu_Loop
        function Menu_LoopSelected(app, event)
            app.Menu_Direct.Checked = false;
            app.Menu_Loop.Checked = true;
            app.mode_loop = "loop";
            app.player_timer.TasksToExecute = Inf;
        end

        % Menu selected function: Menu_CropXY
        function Menu_CropXYSelected(app, event)
            if ~isempty(app.hdle_uROI) && isvalid(app.hdle_uROI)
                selection = uiconfirm(app.Reg3DV212WukongUIFigure,...
                app.region.chmap.IRREVERSIBLE_CROP,...
                app.region.chmap.WARNING, "Icon","warning");
                if selection == "OK"
                    roiXY_coor = ROI2Rectangle(app.hdle_uROI);
                    % xy := [x1,y1;x2,y2] in pixel
                    app.roi_viewer_cur.xy = Coordinate2Pixel(roiXY_coor,...
                        diff(app.UIAxes_Slice.XLim)/app.img_raw.MetaData.width,...
                        diff(app.UIAxes_Slice.YLim)/app.img_raw.MetaData.height);

                    PanelWaiting(app);  %

                    % croping three sources if it's possible
                    if ~isempty(app.img_raw)
                        app.img_raw = ...
                            app.img_raw.vcrop("XY", app.roi_viewer_cur.xy');
                    end

                    % for aligned info
                    if ~isempty(app.img_aligned)
                        app.img_aligned = ...
                            app.img_aligned.vcrop("XY", app.roi_viewer_cur.xy');
                    end

                    % update template defination
                    switch app.viewer_src
                        case "raw"
                            app.refvol = regtmpl(app.img_raw, app.fixdef);
                        case "aligned"
                            app.refvol = regtmpl(app.img_aligned, app.fixdef);
                        otherwise
                    end

                    % for recovery point
                    if ~isempty(app.mdrp.raw)
                        [app.mdrp.raw, ~] = CropXY(app.mdrp.raw,...
                            app.roi_viewer_cur.xy);
                    end
                    if ~isempty(app.mdrp.aligned)
                        [app.mdrp.aligned, ~] = CropXY(app.mdrp.aligned,...
                            app.roi_viewer_cur.xy);
                    end

                    % crop the z-projection
                    if ~isempty(app.img_zproj.raw)
                        [app.img_zproj.raw, ~] = CropXY(...
                            app.img_zproj.raw, app.roi_viewer_cur.xy);
                    end
                    if ~isempty(app.img_zproj.aligned)
                        [app.img_zproj.aligned, ~] = CropXY(...
                            app.img_zproj.aligned, app.roi_viewer_cur.xy);
                    end

                    % Synchronize z projection
                    SourceZProjection(app, [], app.mode_zproj);

                    % Synchronize mROI mask
                    if ~isempty(app.mask_reg)
                        app.mask_reg = ...
                            app.mask_reg.crop("XY", app.roi_viewer_cur.xy');
                    end

                    % remove the exist ROIs if possible
                    delete(app.hdle_uROI);

                    % update the scalebar
                    app.sbar.update(app.img_raw.MetaData);

                    app.img_operators = setdiff(union(app.img_operators, "crop"), "none");
                    GenSysMsg(app, ["XY_CROPPED",""]);

                    PanelRecovered(app);

                    RefreshViewer(app, "XY");
                else
                    return;
                end
            else
                uialert(app.Reg3DV212WukongUIFigure,...
                    app.region.chmap.CONFIRM_ROI_RANGE,...
                    app.region.chmap.WARNING, "Icon","warning");
                return;
            end
        end

        % Menu selected function: Menu_CropZ
        function Menu_CropZSelected(app, event)
            selection = uiconfirm(app.Reg3DV212WukongUIFigure,...
                app.region.chmap.IRREVERSIBLE_CROP,...
                app.region.chmap.WARNING, "Icon","warning");
            if selection == "OK"
                prompt = {['\fontsize{12} ', app.region.chmap.THICKNESS_RANGE.char()]};
                dlgtitle = app.region.chmap.CROP_Z.char();
                dims = [1 40];
                definput = {'1:end'};
                dlgopts.WindowStyle = 'normal';
                dlgopts.Interpreter = "tex";
                dlgout = inputdlg(prompt,dlgtitle,dims,definput,dlgopts);
                if isempty(dlgout)
                    return;
                else
                    dlgout=dlgout{1};
                end
                dlgout = string(dlgout).replace("end",num2str(app.img_raw.MetaData.slices));
                app.roi_viewer_cur.z = [min(str2num(dlgout)), max(str2num(dlgout))]; %#ok<ST2NM>
                if diff(app.roi_viewer_cur.z) < 2
                    uialert(app.Reg3DV212WukongUIFigure, ...
                        app.region.chmap.SLICE_FEW,...
                        app.region.chmap.WARNING, "Icon","warning");
                    return;
                elseif diff(app.roi_viewer_cur.z) < 5
                    slt = uiconfirm(app.Reg3DV212WukongUIFigure,...
                        app.region.chmap.SLICES_FEW_FOR_NEXT_STEP, ...
                        app.region.chmap.WARNING, "Icon","warning");
                    if slt ~= "OK"
                        return;
                    end
                elseif diff(app.roi_viewer_cur.z) >= app.img_raw.MetaData.slices
                    uialert(app.Reg3DV212WukongUIFigure, ...
                        app.region.chmap.SLICES_OVERFLOW,...
                        app.region.chmap.WARNING, "Icon","warning");
                    return;
                end

                PanelWaiting(app);  %

                % croping two sources if it's possible
                if ~isempty(app.img_raw)
                    app.img_raw = app.img_raw.vcrop("Z", app.roi_viewer_cur.z);
                end

                % for aligned movie
                if ~isempty(app.img_aligned)
                    app.img_aligned = app.img_aligned.vcrop("Z", app.roi_viewer_cur.z);
                end

                % update template defination
                switch app.viewer_src
                    case "raw"
                        app.refvol = regtmpl(app.img_raw, app.fixdef);
                    case "aligned"
                        app.refvol = regtmpl(app.img_aligned, app.fixdef);
                    otherwise
                end

                % for recovery point
                if ~isempty(app.mdrp.raw)
                    [app.mdrp.raw, ~] = CropZ(app.mdrp.raw, app.roi_viewer_cur.z);
                end
                if ~isempty(app.mdrp.aligned)
                    [app.mdrp.aligned, ~] = CropZ(app.mdrp.aligned, app.roi_viewer_cur.z);
                end

                % reorder slice axis
                app.sidx_cur = 1;
                SetSlider_Slice(app);

                % reset valid vpl
                app.opt_reg = set(app.opt_reg, "VPL",  ...
                    floor(log(app.img_raw.MetaData.slices)/log(4))+1);

                % Synchronize z projection
                SourceZProjection(app, [], app.mode_zproj);

                % Synchronize mROI mask
                if ~isempty(app.mask_reg)
                    app.mask_reg = ...
                        app.mask_reg.crop("Z", app.roi_viewer_cur.z);
                end

                % Refresh RS handle
                if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                    app.RS_handle.Refresh(app);
                end

                app.img_operators = setdiff(union(app.img_operators, "crop"), "none");
                GenSysMsg(app, ["Z_CROPPED",""]);

                PanelRecovered(app);

                RefreshViewer(app, "Z");
            else
                return;
            end
        end

        % Menu selected function: Menu_LightContrast
        function Menu_LightContrastSelected(app, event)
            if isempty(app.BC_handle) || ~isvalid(app.BC_handle)
                app.BC_handle = BCAdjuster(app);
            end
        end

        % Window button motion function: Reg3DV212WukongUIFigure
        function Reg3DV212WukongUIFigureWindowButtonMotion(app, event)
            % This callback offer realtime cursor position
            if app.loaded_flag == true
                % skip if ROI mode
                if ~isempty(app.hdle_uROI) && isvalid(app.hdle_uROI)
                    return;
                end
                mouse_pos_cur = app.UIAxes_Slice.CurrentPoint(1,1:2);
                if any(mouse_pos_cur < 0) ...
                        || any(mouse_pos_cur>[app.img_raw.MetaData.width,...
                        app.img_raw.MetaData.height])
                    mouse_pos_cur = [];
                else
                    % change the unit of mouse cursor position from world
                    % coordinate to image coordinate
                    R = [0,0;mouse_pos_cur];    % generate fixed vector R
                    [~,x,y] = Coordinate2Pixel(R,...
                        diff(app.UIAxes_Slice.XLim)/app.img_raw.MetaData.width,...
                        diff(app.UIAxes_Slice.YLim)/app.img_raw.MetaData.height);
                    mouse_pos_cur = [x, y];          
                end

                if isempty(mouse_pos_cur)
                    % recover the none title
                    UpdateUIAxes_Slice_Title(app);
                    return;
                end

                % readout pixel intensity from slice
                pixIxy = 0;
                if app.mode_zproj == "none"
                    switch app.viewer_src
                        case "raw"
                            pixIxy = app.img_raw.Movie(y,x,app.cidx_cur,...
                                app.sidx_cur,app.fidx_cur);
                        case "aligned"
                            pixIxy = app.img_aligned.Movie(y,x,app.cidx_cur,...
                                app.sidx_cur,app.fidx_cur);
                        otherwise
                    end
                else
                    switch app.viewer_src
                        case "raw"
                            pixIxy = app.img_zproj.raw(y,x,...
                                app.cidx_cur,app.fidx_cur);
                        case "aligned"
                            pixIxy = app.img_zproj.aligned(y,x,...
                                app.cidx_cur,app.fidx_cur);
                        otherwise
                    end
                end

                UpdateUIAxes_Slice_Title(app,mouse_pos_cur,pixIxy);

                % update the screen utils components
                if ~isempty(app.text_time.ismoving) && app.text_time.ismoving
                    app.text_time.handle.Position = [mouse_pos_cur, 0];
                end
                if ~isempty(app.text_meta.ismoving) && app.text_meta.ismoving
                    app.text_meta.handle.Position = [mouse_pos_cur, 0];
                end
                if ~isempty(app.sbar.Moving) && app.sbar.Moving
                    app.sbar.move(mouse_pos_cur);
                end

            end
        end

        % Value changed function: Switch_Log
        function Switch_LogValueChanged(app, event)
            value = app.Switch_Log.Value;

            app.TextArea_Log.Enable = value;
        end

        % Menu selected function: Menu_RegEvaluate
        function Menu_RegEvaluateSelected(app, event)

            if isempty(app.EV_handle) || ~isvalid(app.EV_handle)
                app.EV_handle = Evaluator(app);
            end
        end

        % Menu selected function: Menu_SaveTimeAs
        function Menu_SaveTimeAsSelected(app, event)
            if ~isempty(app.img_aligned.Time) || ~isempty(app.img_raw.Time)
                if ~isempty(app.filename) && exist(app.filename,"file")
                    [~,fname,~] = fileparts(app.filename);
                else
                    fname = "processing";
                end

                [file, path] = uiputfile(...
                    {'*.tim', [app.region.chmap.TIME_SERIES_FILE.char(), ' (*.tim)'];...
                    '*.txt', [app.region.chmap.TEXT_FILE.char(),' (*.txt)'];...
                    '*.csv', ['CSV (',app.region.chmap.COMMA_SEPARATED.char(),') (*.csv)'];...
                    '*.xlsx', ['Excel ',app.region.chmap.WORKSHEET.char(),'(*.xlsx)'];...
                    '*.xls', ['Excel 97-2003 ',app.region.chmap.WORKSHEET.char(),'(*.xls)']},...
                    app.region.chmap.EXPORT_TIME,...
                    app.path_save + string(fname));

                if isequal(path, 0) || isequal(file, 0)
                    return;
                else
                    fname = fullfile(path,file);

                    [~,~,ext] = fileparts(fname);
                    if ~isempty(app.img_aligned.Time)
                        time = app.img_aligned.Time;
                    else
                        time = app.img_raw.Time;
                    end
                    if ext == ".tim"
                        writematrix(time, fname, "FileType","text");
                    else
                        writematrix(time, fname);
                    end
                    
                    app.path_save = path;
                    GenSysMsg(app, ["TIME_EXPORT_SUCCESS",""]);
                end
            else
                uialert(app.Reg3DV212WukongUIFigure, ...
                    app.region.chmap.TIME_MISSING, ...
                    app.region.chmap.WARNING, "Icon","warning");
            end
        end

        % Menu selected function: Menu_ExportLog
        function Menu_ExportLogSelected(app, event)
            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            path = [pathToMLAPP, filesep, 'log'];
            if ~isfolder(path)
                mkdir(path);
            end
            
            ExportTextArea_Log(app, path);

            GenSysMsg(app, ["LOGFILE_EXPORT_TO", " "+path]);
        end

        % Menu selected function: Menu_ClearLog
        function Menu_ClearLogSelected(app, event)
            
            ClearTextArea_Log(app);

            % clear system message
            app.sysmsg = [];
        end

        % Menu selected function: Menu_GenerateMask_Simply
        function Menu_GenerateMask_SimplySelected(app, event)
            if isempty(app.MG_handle) || ~isvalid(app.MG_handle)
                app.MG_handle = MaskGenerator(app);
            end

        end

        % Menu selected function: Menu_LoadMask_Simply
        function Menu_LoadMask_SimplySelected(app, event)
            % load the mask and apply on current movie

            switch app.masked_flag
                case "uROI"
                    % removing the mask and recovery data
                    app.img_raw.Movie = app.mdrp.raw;
                    app.img_aligned.Movie = app.mdrp.aligned;

                    GenSysMsg(app, ["MASK_REMOVED",""]);

                    % change the record status and menu function
                    app.Menu_LoadMask_Simply.Text = app.region.chmap.IMPORT_MASK;
                    app.Menu_SignalExtractor.Enable = "off";
                    app.masked_flag = "none";
                case "none"
                    % loading mask and applying mask on images
                    pathToMLAPP = fileparts(mfilename('fullpath'));

                    [file, path] = uigetfile({'*.mat', ...
                        ['MAT ',app.region.chmap.FILE.char(),' (*.mat)']}, ...
                        app.region.chmap.IMPORT_MASK,...
                        pathToMLAPP);
                    HoldWindowOn(app);

                    if path ~= 0
                        file = fullfile(path, file);
                        file_stru = load(file);
                        if ~ismember("mask_to_save", fieldnames(file_stru))
                            uialert(app.Reg3DV212WukongUIFigure, ...
                                app.region.chmap.INVALID_MASK_FILE,...
                                app.region.chmap.ERROR, "Icon","error");
                            return;
                        end

                        % mask: 3D logical array
                        mask = file_stru.mask_to_save;    
                        if ndims(mask)~=3 || ...
                                any(size(mask) ~= size(app.img_raw.Movie, [1,2,4]))
                            uialert(app.Reg3DV212WukongUIFigure, ...
                                app.region.chmap.MASK_MISMATCH,...
                                app.region.chmap.ERROR, "Icon","error");
                            return;
                        else
                            % setup the recovery point
                            app.mdrp.raw = app.img_raw.Movie;
                            app.mdrp.aligned = app.img_aligned.Movie;

                            % mask the data
                            D = MaskMovie(app.img_raw.Movie,...
                                mask);
                            app.img_raw.Movie = D;
                            D = MaskMovie(app.img_aligned.Movie,...
                                mask);
                            app.img_aligned.Movie = D;

                            GenSysMsg(app, ["MASK_LOADED",""]);

                            % change the record status and menu function
                            app.Menu_LoadMask_Simply.Text = app.region.chmap.REMOVE;
                            app.Menu_SignalExtractor.Enable = "on";
                            app.masked_flag = "uROI";
                        end
                    end
                otherwise
                    % multiply case, do nothing
            end

            RefreshViewer(app);
        end

        % Value changed function: Button_Run
        function Button_RunValueChanged(app, event)
            value = app.Button_Run.Value;

            if value == true
                if any(app.opt_locked{1,:}) == true
                    RegisterGo(app);
                else
                    RegNotReadyWarn(app);
                end
            else
                % stop the registration
                switch app.kernel.State
                    case app.kernel.KERNEL_ON
                        % turn off the kernel
                        % change the button status
                        app.Button_Run.BackgroundColor = [1, 1, 0];
                        app.Button_Run.Text = app.region.chmap.BREAK;

                        % send the stop signal
                        app.kernel.stop();
                    case app.kernel.KERNEL_OFF

                    otherwise
                        app.Button_Run.Text = app.region.chmap.RUN;
                        app.Button_Run.BackgroundColor = [0.96,0.96,0.96];
                end
            end
        end

        % Menu selected function: Menu_TimeStamp
        function Menu_TimeStampSelected(app, event)
            % switch the checked status
            value = app.Menu_TimeStamp.Checked;

            if value == false
                % new text object on screen
                app.text_time.handle = text(app.UIAxes_Slice,1,10,0,"00:00:00.000",...
                    "FontSize",14,"FontName","Times New Roman","Color",[1,1,0],...
                    "HorizontalAlignment","left");
                time = seconds(app.img_raw.Time);
                time.Format = 'hh:mm:ss.SSS';
                app.text_time.text = string(time);
                app.text_time.handle.String = app.text_time.text(app.fidx_cur);
                app.text_time.ismoving = false;
                app.text_time.handle.ButtonDownFcn = ...
                    createCallbackFcn(app,@TimeText_ButtonDownFcn,true);
                app.Menu_TimeStamp.Checked = "on";
            else
                delete(app.text_time.handle);
                app.text_time.handle = [];
                app.text_time.ismoving = [];
                app.Menu_TimeStamp.Checked = "off";
            end
        end

        % Menu selected function: Menu_MetaMarker
        function Menu_MetaMarkerSelected(app, event)
            % switch the checked status
            value = app.Menu_MetaMarker.Checked;

            if value == false
                % load the meta data file
                metadata = LoadMetaData(app.img_raw.Time);
                if ~isempty(metadata)
                    % new text object on screen
                    app.text_meta.handle = text(app.UIAxes_Slice,app.img_raw.MetaData.width-50,10,0,"water",...
                        "FontSize",14,"FontName","Times New Roman","Color",[1,1,0],...
                        "HorizontalAlignment","right");
                    app.text_meta.text = metadata;
                    app.text_meta.ismoving = false;
                    app.text_meta.handle.ButtonDownFcn = ...
                        createCallbackFcn(app,@MetaText_ButtonDownFcn,true);
                    app.Menu_MetaMarker.Checked = "on";
                end
            else
                delete(app.text_meta.handle);
                app.text_meta.handle = [];
                app.text_meta.text = [];
                app.text_meta.ismoving = [];
                app.Menu_MetaMarker.Checked = "off";
            end
        end

        % Menu selected function: Menu_SaveMovieAs
        function Menu_SaveMovieAsSelected(app, event)
            app.ME_handle = MovieExporter(app);
            waitfor(app.ME_handle);

            if ~isempty(app.movie_config)
                if ~isempty(app.movie_config.movie_name)
                    fname = app.movie_config.movie_name;
                else
                    pathToMLAPP = fileparts(mfilename('fullpath'));
                    t = datetime('now','TimeZone','local','Format','yyMMdd');
                    fname = fullfile(pathToMLAPP, string(t)+".avi");
                end
                % lock the viewer controller and record status
                slider_slice_status = app.Slider_Slice.Enable;
                app.Slider_Slice.Enable = "off";
                app.Slider_Time.Enable = "off";
                key_mode_old =  app.mode_loop;
                app.mode_loop = "one-step";
                app.Menu_KeyMode.Enable = "off";
                frame_idx = app.fidx_cur;

                % set the video writer
                vw = VideoWriter(fname, app.movie_config.movie_type);

                switch app.movie_config.movie_type
                    case "Motion JPEG 2000"
                        vw.CompressionRatio = app.movie_config.compression_ratio;
                        vw.LosslessCompression = app.movie_config.is_nocompression;
                    otherwise
                        if app.movie_config.movie_type == "Motion JPEG AVI"...
                                || app.movie_config.movie_type == "MPEG-4"
                            vw.Quality = app.movie_config.quality;
                        elseif app.movie_config.movie_type == "Uncompressed AVI"
                            %
                        end
                end
                vw.FrameRate = app.movie_config.fps;

                % open the video file
                open(vw);

                app.SetProgressBar(0);
                GenSysMsg(app, ["EXPORTING_MOVIE",""]);

                % setup the capture screen
                ss = get(0).ScreenSize(3:4);
                ss_uifig = app.Reg3DV212WukongUIFigure.Position(3:4);
                ex_fig = figure;
                setFigure(ex_fig,cat(2,[0,0],ss_uifig./ss));
                set(ex_fig,"Visible","off");   % hide the window

                for k = 1:app.img_raw.MetaData.frames
                    % reset the frame
                    app.fidx_cur = k;
                    RefreshViewer(app);

                    % clear capture figure and copy objects
                    clf(ex_fig);
                    copyobj(app.UIAxes_Slice, ex_fig);
                    ax = get(ex_fig,"CurrentAxes");
                    if ~isempty(app.hdle_uROI) && isvalid(app.hdle_uROI)
                        [rect,w,h] = ROI2Rectangle(app.hdle_uROI);
                        f = getframe(ax,[rect(1,1),rect(2,2),w,h]);
                    else
                        f = getframe(ax);
                    end

                    % resize the rgb
                    if app.movie_config.is_rescale
                        rgb = frame2im(f);
                        rgb = imresize(rgb,...
                            [app.movie_config.height, app.movie_config.width],...
                            app.movie_config.interp_method);
                        f = im2frame(rgb);
                    end

                    % write the frame
                    writeVideo(vw, f);

                    % update bar processing
                    app.SetProgressBar(k/app.img_raw.MetaData.frames);
                end

                % close the video file
                close(vw);
                % close the capture screen
                close(ex_fig);

                % unlock the viewer controller and recover key_mode
                app.Slider_Slice.Enable = slider_slice_status;
                app.fidx_cur = frame_idx;
                app.Slider_Time.Enable = "on";
                app.mode_loop = key_mode_old;
                app.Menu_KeyMode.Enable = "on";

                RefreshViewer(app);

                GenSysMsg(app, ["MOVIE_EXPORTED_SUCCESS",""]);
            end

        end

        % Menu selected function: Menu_RegRange
        function Menu_RegRangeSelected(app, event)
            prompt = {['\fontsize{12} ', app.region.chmap.TIME_RANGE.char()]};
            dlgtitle = app.region.chmap.UNREGISTRATERED_FRAMES.char();
            dims = [1 40];
            definput = {char(app.frames_reg(1))};
            dlgopts.WindowStyle = 'normal';
            dlgopts.Interpreter = "tex";
            dlgout = inputdlg(prompt,dlgtitle,dims,definput,dlgopts);
            if isempty(dlgout)
                return;
            else
                dlgout=dlgout{1};
            end

            % validate inputs updation
            rf = str2num(strrep(dlgout,'end', ...
                num2str(app.img_raw.MetaData.frames))); %#ok<ST2NM>
            if isempty(rf)
                uialert(app.Reg3DV212WukongUIFigure, ...
                    app.region.chmap.INVALID_FRAME_ORDER, ...
                    app.region.chmap.WARNING, "Icon","warning");
            else
                if numel(rf) > app.img_raw.MetaData.frames
                    uialert(app.Reg3DV212WukongUIFigure, ...
                        app.region.chmap.FRAME_OVERFLOW, ...
                        app.region.chmap.WARNING, "Icon","warning");
                else
                    app.frames_reg(1) = string(dlgout);
                end
            end
        end

        % Menu selected function: Menu_ScaleBar
        function Menu_ScaleBarSelected(app, event)
            app.sbar.Locked = true;

            app.SB_handle = ScaleBar(app);
            % hold the handle until end setting
            uiwait(app.SB_handle.ScaleBarUIFigure);

            app.sbar.Locked = false;

        end

        % Close request function: Reg3DV212WukongUIFigure
        function Reg3DV212WukongUIFigureCloseRequest(app, event)
            % finish the update setting
            is_update = app.update_opts.is_update;

            % free this app
            Free(app);

            % check the update option
            if is_update == true
                run(fullfile(userpath, "auto_update.m"));
                delete(fullfile(userpath, "auto_update.m"));
            end
        end

        % Menu selected function: Menu_RegOptions
        function Menu_RegOptionsSelected(app, event)
            % This function assigns different registration setting by
            % using channel(s) number
            if isempty(app.RS_handle) || ~isvalid(app.RS_handle)
                if numel(app.c_order) == 1
                    % calling the one channel setting subapp
                    app.RS_handle = OCRegSetter(app);
                elseif numel(app.c_order) == 2
                    % calling the two channels setting subapp
                    app.RS_handle = TCRegSetter(app);
                else
                    app.RS_handle = [];
                    uialert(app.Reg3DV212WukongUIFigure, ...
                       app.region.chmap.UNSUPPORTED_CHANNEL_NUMBER, ...
                        app.region.chmap.ERROR, "Icon","error");
                    return;
                end
            end
        end

        % Menu selected function: Menu_Chinese
        function Menu_ChineseSelected(app, event)
            app.Menu_Chinese.Checked = "on";
            app.Menu_English.Checked = "off";

            SetSystemLanguage(app,"zh_CN");
        end

        % Menu selected function: Menu_English
        function Menu_EnglishSelected(app, event)
            app.Menu_Chinese.Checked = "off";
            app.Menu_English.Checked = "on";

            SetSystemLanguage(app, "en_US");
        end

        % Menu selected function: Menu_MaxZProjection
        function Menu_MaxZProjectionSelected(app, event)
            switch app.Menu_MaxZProjection.Checked
                case "on"
                    app.Menu_MaxZProjection.Checked = "off";
                    app.mode_zproj = "none";
                    SetSlider_Slice(app,'on');
                case "off"
                    app.Menu_MaxZProjection.Checked = "on";
                    app.Menu_MinZProjection.Checked = "off";
                    app.Menu_MedianZProjection.Checked = "off";
                    app.Menu_MeanZProjection.Checked = "off";

                    app.mode_zproj = "max";
                    SetSlider_Slice(app,'freeze');

                    PanelWaiting(app);
                    SourceZProjection(app,[],"max");
                    PanelRecovered(app);
                otherwise
            end

            RefreshViewer(app, "Z");
        end

        % Menu selected function: Menu_MinZProjection
        function Menu_MinZProjectionSelected(app, event)
            switch app.Menu_MinZProjection.Checked
                case "on"
                    app.Menu_MinZProjection.Checked = "off";
                    app.mode_zproj = "none";
                    SetSlider_Slice(app,'on');
                case "off"
                    app.Menu_MaxZProjection.Checked = "off";
                    app.Menu_MinZProjection.Checked = "on";
                    app.Menu_MedianZProjection.Checked = "off";
                    app.Menu_MeanZProjection.Checked = "off";

                    app.mode_zproj = "min";
                    SetSlider_Slice(app,'freeze');

                    PanelWaiting(app);
                    SourceZProjection(app,[],"min");
                    PanelRecovered(app);
                otherwise
            end

            RefreshViewer(app, "Z");
        end

        % Menu selected function: Menu_MedianZProjection
        function Menu_MedianZProjectionSelected(app, event)
            switch app.Menu_MedianZProjection.Checked
                case "on"
                    app.Menu_MedianZProjection.Checked = "off";
                    app.mode_zproj = "none";
                    SetSlider_Slice(app,'on');
                case "off"
                    app.Menu_MaxZProjection.Checked = "off";
                    app.Menu_MinZProjection.Checked = "off";
                    app.Menu_MedianZProjection.Checked = "on";
                    app.Menu_MeanZProjection.Checked = "off";

                    app.mode_zproj = "median";
                    SetSlider_Slice(app,'freeze');

                    PanelWaiting(app);
                    SourceZProjection(app,[],"median");
                    PanelRecovered(app);
                otherwise
            end

            RefreshViewer(app, "Z");
        end

        % Menu selected function: Menu_MeanZProjection
        function Menu_MeanZProjectionSelected(app, event)
            switch app.Menu_MeanZProjection.Checked
                case "on"
                    app.Menu_MeanZProjection.Checked = "off";
                    app.mode_zproj = "none";
                    SetSlider_Slice(app,'on');
                case "off"
                    app.Menu_MaxZProjection.Checked = "off";
                    app.Menu_MinZProjection.Checked = "off";
                    app.Menu_MedianZProjection.Checked = "off";
                    app.Menu_MeanZProjection.Checked = "on";

                    app.mode_zproj = "mean";
                    SetSlider_Slice(app,'freeze');

                    PanelWaiting(app);
                    SourceZProjection(app,[],"mean");
                    PanelRecovered(app);
                otherwise
            end

            RefreshViewer(app, "Z");
        end

        % Menu selected function: Menu_Quit
        function Menu_QuitSelected(app, event)
            
            Reg3DV212WukongUIFigureCloseRequest(app, event);
        end

        % Menu selected function: Menu_ChannelAlignment
        function Menu_ChannelAlignmentSelected(app, event)
            if app.flag_calign == false
                app.flag_calign = true;
                app.Menu_ChannelAlignment.Checked = "on";
                % lock the player controller
                LockViewerController(app);
                % call subapp channel alignment
                if isempty(app.CC_handle) || ~isvalid(app.CC_handle)
                    app.CC_handle = ChannelCorrector(app);
                    waitfor(app.CC_handle);
                end
                UnlockViewerController(app);
                app.flag_calign = false;
                app.Menu_ChannelAlignment.Checked = "off";
                RefreshViewer(app);
            else
                delete(app.CC_handle);
                UnlockViewerController(app);
                app.flag_calign = false;
                app.Menu_ChannelAlignment.Checked = "off";
                RefreshViewer(app);
            end
        end

        % Menu selected function: Menu_RemoveBackgroundAuto
        function Menu_RemoveBackgroundAutoSelected(app, event)
            % remove the possible status
            app.Menu_RemoveBackgroundFix.Checked = "off";
            app.Menu_RemoveBackgroundAuto.Checked = ...
                ~app.Menu_RemoveBackgroundAuto.Checked;

            PanelWaiting(app);

            if app.flag_bkg_removed == true
                RecoverCameraBKG(app);
            else
                if app.Menu_RemoveBackgroundAuto.Checked == "on"
                    RemoveCameraBKG(app);
                end
            end

            % Update the display resource
            SourceZProjection(app, [], app.mode_zproj);

            PanelRecovered(app);

            RefreshViewer(app);
        end

        % Menu selected function: Menu_RemoveBackgroundFix
        function Menu_RemoveBackgroundFixSelected(app, event)
            % remove the possible status
            app.Menu_RemoveBackgroundAuto.Checked = "off";
            app.Menu_RemoveBackgroundFix.Checked = ...
                ~ app.Menu_RemoveBackgroundFix.Checked;

            PanelWaiting(app);

            if app.flag_bkg_removed == true
                RecoverCameraBKG(app);
            else
                if app.Menu_RemoveBackgroundFix.Checked == "on"
                    status = RemoveCameraBKG(app);
                    if status ~= 0
                        % undo
                        app.Menu_RemoveBackgroundFix.Checked = "off";
                    end
                end
            end

            % Update the display resource
            SourceZProjection(app, [], app.mode_zproj);

            PanelRecovered(app);

            RefreshViewer(app);
        end

        % Menu selected function: Menu_SubmitBug
        function Menu_SubmitBugSelected(app, event)
            if isempty(app.sftp_to_labnas)
                app.sftp_to_labnas = getSFTP(app.BUG_REPORT_HOST);
            end

            if isempty(app.BR_handle) || ~isvalid(app.BR_handle)
                if ~isempty(app.sftp_to_labnas)
                    app.BR_handle = BugReporter(app, app.sftp_to_labnas);
                end
            end
        end

        % Window scroll wheel function: Reg3DV212WukongUIFigure
        function Reg3DV212WukongUIFigureWindowScrollWheel(app, event)
            vSC = event.VerticalScrollCount;

            % update the slice viewer
            if ~isempty(app.hdle_image) && isvalid(app.hdle_image)
                if (app.UIAxes_Slice.CurrentPoint(2,2) > app.scroll_ylim(1)) ...
                        && (app.UIAxes_Slice.CurrentPoint(2,2) < app.scroll_ylim(2))
                    dz = 0;
                    if vSC < 0
                        while dz > vSC, NextSlicePtr(app); dz=dz-1; end
                    else
                        while dz < vSC, PrevSlicePtr(app); dz=dz+1; end
                    end
                    RefreshViewer(app, "Z");
                end
            end
            
        end

        % Menu selected function: Menu_RunModalDistributed
        function Menu_RunModalDistributedSelected(app, event)
            app.Menu_RunModalDistributed.Checked = "on";
            app.Menu_RunModalExclusive.Checked = "off";
            app.flag_distrib = true;

            ChangeRunModal(app);
        end

        % Menu selected function: Menu_RunModalExclusive
        function Menu_RunModalExclusiveSelected(app, event)
            if app.flag_server
                slt = uiconfirm(app.Reg3DV212WukongUIFigure, ...
                    app.region.chmap.EXCLUSIVE_WARNING, ...
                    app.region.chmap.WARNING, ...
                    "Icon","warning");
                if slt == "OK"
                    app.Menu_RunModalDistributed.Checked = "off";
                    app.Menu_RunModalExclusive.Checked = "on";
                    app.flag_distrib = false;
                else
                    return;
                end
            else
                avail_mem = GetAvailableMemory();
                tot_mem = app.img_raw.Bytes.map + app.img_aligned.Bytes.map;
                if avail_mem > tot_mem * 2.5       % 2.5 as capacity coefficient
                    app.Menu_RunModalDistributed.Checked = "off";
                    app.Menu_RunModalExclusive.Checked = "on";
                    app.flag_distrib = false;
                else
                    uialert(app.Reg3DV212WukongUIFigure, ...
                        app.region.chmap.LOW_MEMORY_WARNING, ...
                        app.region.chmap.WARNING, ...
                        "Icon","warning");
                end
            end

            ChangeRunModal(app);
        end

        % Menu selected function: Menu_TaskViewer
        function Menu_TaskViewerSelected(app, event)
            % This function calls task viewer gui
            if isempty(app.TV_handle) || ~isvalid(app.TV_handle)
                app.TV_handle = TaskViewer(app);
            end
        end

        % Size changed function: Panel_SliceViewer
        function Panel_SliceViewerSizeChanged(app, event)
            if ~isempty(app.hdle_image) && isvalid(app.hdle_image)
                UpdateYScrollLim(app);
                ScalingProgressBar(app);
            end
        end

        % Image clicked function: Image_Update
        function Image_UpdateClicked(app, event)
            % Click the image for updating
            [~, url, ver] = acquire_update(app.update_opts.repository, Register.VER);
            [path, file, ext] = fileparts(url);

            % read the update information from remote host
            fileToUpdateTxt = [path, filesep, 'update ', 'V', ver, '.txt'];
            if isfile(fileToUpdateTxt)
                update_info = readlines(fileToUpdateTxt);
                slt = uiconfirm(app.Reg3DV212WukongUIFigure, string(update_info), ...
                    app.region.chmap.INFORMATION, "Icon","info","Options",{'Update','Cancel'});
                if ~strcmp(slt, 'Update')
                    return;
                end
            end

            fileToMLAPP = [file, ext];
            bar = uiprogressdlg(app.Reg3DV212WukongUIFigure,...
                "Icon","info", "Indeterminate","on",...
                "Message",app.region.chmap.DOWNLOADING);

            if ~exist(['downloads', filesep], "dir")
                mkdir(['downloads', filesep]);
            end
            copyfile(url, ['downloads', filesep, fileToMLAPP]);

            close(bar);

            % call outer script generator
            [~, att] = fileattrib(['downloads', filesep, fileToMLAPP]);

            status = reg3d_update(string(att.Name), app.update_opts.auto_clean);

            % set the update flag
            app.update_opts.is_update = status;

            if status == true
                uialert(app.Reg3DV212WukongUIFigure,...
                    app.region.chmap.FINISH_UPDATE, ...
                    app.region.chmap.INFORMATION,"Icon","success");
            else
                uialert(app.Reg3DV212WukongUIFigure,...
                    app.region.chmap.UPDATE_SCRIPT_FAILED, ...
                    app.region.chmap.ERROR, "Icon","error");
                delete(['downloads', filesep, fileToMLAPP]);
            end
        end

        % Button pushed function: Button_HistoryManager
        function Button_HistoryManagerPushed(app, event)
            uialert(app.Reg3DV212WukongUIFigure, ...
                app.region.chmap.DEVELOPING, ...
                app.region.chmap.INFORMATION, ...
                "Icon","info");
        end

        % Menu selected function: Menu_PyPath
        function Menu_PyPathSelected(app, event)
            % this call back help setting the python environment manually
            if ispc()
                defname = "python.exe";
            elseif isunix()
                defname = "python";
            end
            [file, path] = uigetfile(defname, ...
                app.region.chmap.PYTHON_INTERPRETER_SELECTION);

            if ~isnumeric(file)
                file = fullfile(path, file);
                try
                    pyenv(Version=file, ExecutionMode="OutOfProcess");
                catch ME
                    throwAsCaller(ME);
                end
            end
        end

        % Menu selected function: Menu_LoadMask_Multiply
        function Menu_LoadMask_MultiplySelected(app, event)
            % load mask interface: come from Nuclear Counter currently

            switch app.masked_flag
                case "mROI"
                    % removing the mask and recovery data
                    ClearMultiplyROIs(app);
                    app.mROI_hl = [];
                    app.mask_reg = [];  % auto deconstruct

                    GenSysMsg(app, ["MASK_REMOVED",""]);

                    % change the record status and menu function
                    app.Menu_LoadMask_Multiply.Text = app.region.chmap.IMPORT_MASK;
                    app.Menu_SignalExtractor.Enable = "off";
                    app.masked_flag = "none";

                    % close possible signal estimater
                    CloseSubApp(app, "SE");
                case "none"
                    % loading the mask and applying mask on images
                    pathToMLAPP = fileparts(mfilename('fullpath'));

                    [file, path] = uigetfile({'*.mat', ...
                        ['MAT ',app.region.chmap.FILE.char(),' (*.mat)']}, ...
                        app.region.chmap.IMPORT_MASK,...
                        pathToMLAPP);
                    HoldWindowOn(app);

                    if path ~= 0
                        file = fullfile(path, file);

                        file_stru = load(file);
                        if ~isempty(setxor(["mask", "rois", "label"], ...
                                fieldnames(file_stru)))
                            uialert(app.Reg3DV212WukongUIFigure, ...
                                app.region.chmap.INVALID_MASK_FILE,...
                                app.region.chmap.ERROR, "Icon","error");
                            return;
                        end

                        % mask: sparse double matrix
                        mask = file_stru.mask;

                        if ~ismatrix(mask) || ...
                                numel(app.refvol.RefVol) ~= size(mask, 1)
                            uialert(app.Reg3DV212WukongUIFigure, ...
                                app.region.chmap.MASK_MISMATCH,...
                                app.region.chmap.ERROR, "Icon","error");
                            return;
                        else
                            app.mask_reg = regmask.recover(...
                                mask, size(app.refvol.RefVol));

                            app.mROI_hl = false(app.mask_reg.NumComps, 1);

                            GenSysMsg(app, ["MASK_LOADED",""]);

                            % change the record status and menu function
                            app.Menu_LoadMask_Multiply.Text = app.region.chmap.REMOVE;
                            app.Menu_SignalExtractor.Enable = "on";
                            app.masked_flag = "mROI";
                        end
                    end
                otherwise
                    % simply case, do nothing
            end

            RefreshViewer(app, "Z");
        end

        % Menu selected function: Menu_GenerateMask_Multiply
        function Menu_GenerateMask_MultiplySelected(app, event)
            % TODO: introduce the object extractor
            
        end

        % Menu selected function: Menu_SignalExtractor
        function Menu_SignalExtractorSelected(app, event)
            % TODO: introduce the activity extractor
            if isempty(app.SE_handle) || ~isvalid(app.SE_handle)
                app.SE_handle = SignalEstimator(app);
            end
        end

        % Menu selected function: Menu_AutoClearLog
        function Menu_AutoClearLogSelected(app, event)
            app.auto_clear_log_flag = ...
                ~app.auto_clear_log_flag;

            app.Menu_AutoClearLog.Checked = app.auto_clear_log_flag;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Reg3DV212WukongUIFigure = uifigure('Visible', 'off');
            app.Reg3DV212WukongUIFigure.Name = 'Reg3D V2.1.2 (Wukong)';
            app.Reg3DV212WukongUIFigure.Icon = fullfile(pathToMLAPP, 'sources', 'icon.png');
            app.Reg3DV212WukongUIFigure.CloseRequestFcn = createCallbackFcn(app, @Reg3DV212WukongUIFigureCloseRequest, true);
            app.Reg3DV212WukongUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @Reg3DV212WukongUIFigureWindowButtonMotion, true);
            app.Reg3DV212WukongUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @Reg3DV212WukongUIFigureWindowScrollWheel, true);
            app.Reg3DV212WukongUIFigure.KeyPressFcn = createCallbackFcn(app, @Reg3DV212WukongUIFigureKeyPress, true);
            app.Menu_File = uimenu(app.Reg3DV212WukongUIFigure);
            app.Menu_File.Tooltip = {'导入与导出'};
            app.Menu_File.Text = '文件';
            app.Menu_LoadFile = uimenu(app.Menu_File);
            app.Menu_LoadFile.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoadFileSelected, true);
            app.Menu_LoadFile.Text = '加载文件';
            app.Menu_SaveImageAs = uimenu(app.Menu_File);
            app.Menu_SaveImageAs.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveImageAsSelected, true);
            app.Menu_SaveImageAs.Separator = 'on';
            app.Menu_SaveImageAs.Text = '导出图像';
            app.Menu_SaveTimeAs = uimenu(app.Menu_File);
            app.Menu_SaveTimeAs.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveTimeAsSelected, true);
            app.Menu_SaveTimeAs.Text = '导出时间';
            app.Menu_SaveMovieAs = uimenu(app.Menu_File);
            app.Menu_SaveMovieAs.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveMovieAsSelected, true);
            app.Menu_SaveMovieAs.Text = '导出影片';
            app.Menu_Quit = uimenu(app.Menu_File);
            app.Menu_Quit.MenuSelectedFcn = createCallbackFcn(app, @Menu_QuitSelected, true);
            app.Menu_Quit.Separator = 'on';
            app.Menu_Quit.Text = '退出';
            app.Menu_Image = uimenu(app.Reg3DV212WukongUIFigure);
            app.Menu_Image.Tooltip = {'图像与标注'};
            app.Menu_Image.Text = '图像';
            app.Menu_LightContrast = uimenu(app.Menu_Image);
            app.Menu_LightContrast.MenuSelectedFcn = createCallbackFcn(app, @Menu_LightContrastSelected, true);
            app.Menu_LightContrast.Text = '亮度/对比度';
            app.Menu_ZProjection = uimenu(app.Menu_Image);
            app.Menu_ZProjection.Text = 'Z投影';
            app.Menu_MaxZProjection = uimenu(app.Menu_ZProjection);
            app.Menu_MaxZProjection.MenuSelectedFcn = createCallbackFcn(app, @Menu_MaxZProjectionSelected, true);
            app.Menu_MaxZProjection.Text = '最大值';
            app.Menu_MinZProjection = uimenu(app.Menu_ZProjection);
            app.Menu_MinZProjection.MenuSelectedFcn = createCallbackFcn(app, @Menu_MinZProjectionSelected, true);
            app.Menu_MinZProjection.Text = '最小值';
            app.Menu_MedianZProjection = uimenu(app.Menu_ZProjection);
            app.Menu_MedianZProjection.MenuSelectedFcn = createCallbackFcn(app, @Menu_MedianZProjectionSelected, true);
            app.Menu_MedianZProjection.Text = '中位值';
            app.Menu_MeanZProjection = uimenu(app.Menu_ZProjection);
            app.Menu_MeanZProjection.MenuSelectedFcn = createCallbackFcn(app, @Menu_MeanZProjectionSelected, true);
            app.Menu_MeanZProjection.Text = '平均值';
            app.Menu_ChannelAlignment = uimenu(app.Menu_Image);
            app.Menu_ChannelAlignment.MenuSelectedFcn = createCallbackFcn(app, @Menu_ChannelAlignmentSelected, true);
            app.Menu_ChannelAlignment.Text = '通道对齐';
            app.Menu_RemoveBackGround = uimenu(app.Menu_Image);
            app.Menu_RemoveBackGround.Text = '移除背景';
            app.Menu_RemoveBackgroundAuto = uimenu(app.Menu_RemoveBackGround);
            app.Menu_RemoveBackgroundAuto.MenuSelectedFcn = createCallbackFcn(app, @Menu_RemoveBackgroundAutoSelected, true);
            app.Menu_RemoveBackgroundAuto.Text = '自动';
            app.Menu_RemoveBackgroundFix = uimenu(app.Menu_RemoveBackGround);
            app.Menu_RemoveBackgroundFix.MenuSelectedFcn = createCallbackFcn(app, @Menu_RemoveBackgroundFixSelected, true);
            app.Menu_RemoveBackgroundFix.Text = '固定';
            app.Menu_MaskManager_Simply = uimenu(app.Menu_Image);
            app.Menu_MaskManager_Simply.Separator = 'on';
            app.Menu_MaskManager_Simply.Text = '单连通蒙版';
            app.Menu_GenerateMask_Simply = uimenu(app.Menu_MaskManager_Simply);
            app.Menu_GenerateMask_Simply.MenuSelectedFcn = createCallbackFcn(app, @Menu_GenerateMask_SimplySelected, true);
            app.Menu_GenerateMask_Simply.Text = '生成蒙版';
            app.Menu_LoadMask_Simply = uimenu(app.Menu_MaskManager_Simply);
            app.Menu_LoadMask_Simply.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoadMask_SimplySelected, true);
            app.Menu_LoadMask_Simply.Text = '导入蒙版';
            app.Menu_Comments = uimenu(app.Menu_Image);
            app.Menu_Comments.Text = '添加注释';
            app.Menu_TimeStamp = uimenu(app.Menu_Comments);
            app.Menu_TimeStamp.MenuSelectedFcn = createCallbackFcn(app, @Menu_TimeStampSelected, true);
            app.Menu_TimeStamp.Text = '时间戳';
            app.Menu_ScaleBar = uimenu(app.Menu_Comments);
            app.Menu_ScaleBar.MenuSelectedFcn = createCallbackFcn(app, @Menu_ScaleBarSelected, true);
            app.Menu_ScaleBar.Text = '比例 / 尺';
            app.Menu_MetaMarker = uimenu(app.Menu_Comments);
            app.Menu_MetaMarker.MenuSelectedFcn = createCallbackFcn(app, @Menu_MetaMarkerSelected, true);
            app.Menu_MetaMarker.Text = '元数据';
            app.Menu_Crop3D = uimenu(app.Menu_Image);
            app.Menu_Crop3D.Separator = 'on';
            app.Menu_Crop3D.Text = '体积裁剪';
            app.Menu_CropXY = uimenu(app.Menu_Crop3D);
            app.Menu_CropXY.MenuSelectedFcn = createCallbackFcn(app, @Menu_CropXYSelected, true);
            app.Menu_CropXY.Text = 'XY裁剪';
            app.Menu_CropZ = uimenu(app.Menu_Crop3D);
            app.Menu_CropZ.MenuSelectedFcn = createCallbackFcn(app, @Menu_CropZSelected, true);
            app.Menu_CropZ.Text = 'Z裁剪';
            app.Menu_CropT = uimenu(app.Menu_Image);
            app.Menu_CropT.MenuSelectedFcn = createCallbackFcn(app, @Menu_CropTSelected, true);
            app.Menu_CropT.Text = '帧裁剪';
            app.Menu_Options = uimenu(app.Reg3DV212WukongUIFigure);
            app.Menu_Options.Tooltip = {'设置与首选项'};
            app.Menu_Options.Text = '选项';
            app.Menu_SaveSetting = uimenu(app.Menu_Options);
            app.Menu_SaveSetting.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveSettingSelected, true);
            app.Menu_SaveSetting.Separator = 'on';
            app.Menu_SaveSetting.Text = '导出预设';
            app.Menu_LoadSetting = uimenu(app.Menu_Options);
            app.Menu_LoadSetting.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoadSettingSelected, true);
            app.Menu_LoadSetting.Text = '加载预设';
            app.Menu_Animation = uimenu(app.Menu_Options);
            app.Menu_Animation.Separator = 'on';
            app.Menu_Animation.Text = '动画';
            app.Menu_1F = uimenu(app.Menu_Animation);
            app.Menu_1F.MenuSelectedFcn = createCallbackFcn(app, @Menu_1FSelected, true);
            app.Menu_1F.Text = '1X';
            app.Menu_5F = uimenu(app.Menu_Animation);
            app.Menu_5F.MenuSelectedFcn = createCallbackFcn(app, @Menu_5FSelected, true);
            app.Menu_5F.Text = '5X';
            app.Menu_25F = uimenu(app.Menu_Animation);
            app.Menu_25F.MenuSelectedFcn = createCallbackFcn(app, @Menu_25FSelected, true);
            app.Menu_25F.Text = '25X';
            app.Menu_KeyMode = uimenu(app.Menu_Options);
            app.Menu_KeyMode.Text = '播放模式';
            app.Menu_Direct = uimenu(app.Menu_KeyMode);
            app.Menu_Direct.MenuSelectedFcn = createCallbackFcn(app, @Menu_DirectSelected, true);
            app.Menu_Direct.Text = '单向';
            app.Menu_Loop = uimenu(app.Menu_KeyMode);
            app.Menu_Loop.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoopSelected, true);
            app.Menu_Loop.Text = '循环';
            app.Menu_Register = uimenu(app.Menu_Options);
            app.Menu_Register.Separator = 'on';
            app.Menu_Register.Text = '配准器';
            app.Menu_RegOptions = uimenu(app.Menu_Register);
            app.Menu_RegOptions.MenuSelectedFcn = createCallbackFcn(app, @Menu_RegOptionsSelected, true);
            app.Menu_RegOptions.Text = '参数设置';
            app.Menu_RegRange = uimenu(app.Menu_Register);
            app.Menu_RegRange.MenuSelectedFcn = createCallbackFcn(app, @Menu_RegRangeSelected, true);
            app.Menu_RegRange.Text = '配准范围';
            app.Menu_RegEvaluate = uimenu(app.Menu_Register);
            app.Menu_RegEvaluate.MenuSelectedFcn = createCallbackFcn(app, @Menu_RegEvaluateSelected, true);
            app.Menu_RegEvaluate.Text = '结果评估';
            app.Menu_RunModal = uimenu(app.Menu_Options);
            app.Menu_RunModal.Separator = 'on';
            app.Menu_RunModal.Text = '运行模态';
            app.Menu_RunModalDistributed = uimenu(app.Menu_RunModal);
            app.Menu_RunModalDistributed.MenuSelectedFcn = createCallbackFcn(app, @Menu_RunModalDistributedSelected, true);
            app.Menu_RunModalDistributed.Checked = 'on';
            app.Menu_RunModalDistributed.Text = '分布式';
            app.Menu_RunModalExclusive = uimenu(app.Menu_RunModal);
            app.Menu_RunModalExclusive.MenuSelectedFcn = createCallbackFcn(app, @Menu_RunModalExclusiveSelected, true);
            app.Menu_RunModalExclusive.Text = '独占';
            app.Menu_TaskViewer = uimenu(app.Menu_Options);
            app.Menu_TaskViewer.MenuSelectedFcn = createCallbackFcn(app, @Menu_TaskViewerSelected, true);
            app.Menu_TaskViewer.Text = '任务浏览器';
            app.Menu_Advanced = uimenu(app.Reg3DV212WukongUIFigure);
            app.Menu_Advanced.Text = '高级';
            app.Menu_PyPath = uimenu(app.Menu_Advanced);
            app.Menu_PyPath.MenuSelectedFcn = createCallbackFcn(app, @Menu_PyPathSelected, true);
            app.Menu_PyPath.Text = 'Python 解释器';
            app.Menu_MaskManager_Multiply = uimenu(app.Menu_Advanced);
            app.Menu_MaskManager_Multiply.Text = '多连通蒙版';
            app.Menu_GenerateMask_Multiply = uimenu(app.Menu_MaskManager_Multiply);
            app.Menu_GenerateMask_Multiply.MenuSelectedFcn = createCallbackFcn(app, @Menu_GenerateMask_MultiplySelected, true);
            app.Menu_GenerateMask_Multiply.Text = '生成蒙版';
            app.Menu_LoadMask_Multiply = uimenu(app.Menu_MaskManager_Multiply);
            app.Menu_LoadMask_Multiply.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoadMask_MultiplySelected, true);
            app.Menu_LoadMask_Multiply.Text = '导入蒙版';
            app.Menu_SignalExtractor = uimenu(app.Menu_Advanced);
            app.Menu_SignalExtractor.MenuSelectedFcn = createCallbackFcn(app, @Menu_SignalExtractorSelected, true);
            app.Menu_SignalExtractor.Text = '信号提取器';
            app.Menu_Help = uimenu(app.Reg3DV212WukongUIFigure);
            app.Menu_Help.Tooltip = {'帮助与更新'};
            app.Menu_Help.Text = '帮助';
            app.Menu_About = uimenu(app.Menu_Help);
            app.Menu_About.MenuSelectedFcn = createCallbackFcn(app, @Menu_AboutSelected, true);
            app.Menu_About.Text = '关于';
            app.Menu_Documents = uimenu(app.Menu_Help);
            app.Menu_Documents.MenuSelectedFcn = createCallbackFcn(app, @Menu_DocumentsSelected, true);
            app.Menu_Documents.Text = '文档';
            app.Menu_Language = uimenu(app.Menu_Help);
            app.Menu_Language.Separator = 'on';
            app.Menu_Language.Text = '语言';
            app.Menu_Chinese = uimenu(app.Menu_Language);
            app.Menu_Chinese.MenuSelectedFcn = createCallbackFcn(app, @Menu_ChineseSelected, true);
            app.Menu_Chinese.Text = '中文';
            app.Menu_English = uimenu(app.Menu_Language);
            app.Menu_English.MenuSelectedFcn = createCallbackFcn(app, @Menu_EnglishSelected, true);
            app.Menu_English.Text = 'English';
            app.Menu_SubmitBug = uimenu(app.Menu_Help);
            app.Menu_SubmitBug.MenuSelectedFcn = createCallbackFcn(app, @Menu_SubmitBugSelected, true);
            app.Menu_SubmitBug.Text = '协助改进';
            app.GridLayout8 = uigridlayout(app.Reg3DV212WukongUIFigure);
            app.Panel_Control = uipanel(app.GridLayout8);
            app.Panel_Control.Tooltip = {'控制配准程序执行'};
            app.Panel_Control.Title = '控制面板';
            app.GridLayout2 = uigridlayout(app.Panel_Control);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Switch_Log = uiswitch(app.GridLayout3, 'rocker');
            app.Switch_Log.Items = {'关', '开'};
            app.Switch_Log.ItemsData = {'off', 'on'};
            app.Switch_Log.ValueChangedFcn = createCallbackFcn(app, @Switch_LogValueChanged, true);
            app.Switch_Log.Tooltip = {'日志开关，关闭时停止输出'};
            app.Switch_Log.Value = 'on';
            app.TextArea_Log = uitextarea(app.GridLayout3);
            app.TextArea_Log.Editable = 'off';
            app.TextArea_Log.Placeholder = '欢迎使用Reg3D App';
            app.Label_20 = uilabel(app.GridLayout3);
            app.Label_20.Text = '日志';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.Label_19 = uilabel(app.GridLayout4);
            app.Label_19.Text = '浏览源';
            app.DropDown_Source = uidropdown(app.GridLayout4);
            app.DropDown_Source.Items = {};
            app.DropDown_Source.ItemsData = {'RAW', 'ALIGNED'};
            app.DropDown_Source.ValueChangedFcn = createCallbackFcn(app, @DropDown_SourceValueChanged, true);
            app.DropDown_Source.Tooltip = {'选择浏览器数据源'};
            app.DropDown_Source.Value = {};
            app.Button_Run = uibutton(app.GridLayout4, 'state');
            app.Button_Run.ValueChangedFcn = createCallbackFcn(app, @Button_RunValueChanged, true);
            app.Button_Run.Tooltip = {'运行配准程序'};
            app.Button_Run.Icon = fullfile(pathToMLAPP, 'sources', 'run.png');
            app.Button_Run.Text = '启动';
            app.Panel_SliceViewer = uipanel(app.GridLayout8);
            app.Panel_SliceViewer.Title = '片层浏览器';
            app.Panel_SliceViewer.SizeChangedFcn = createCallbackFcn(app, @Panel_SliceViewerSizeChanged, true);
            app.GridLayout_Viewer = uigridlayout(app.Panel_SliceViewer);
            app.Label_2 = uilabel(app.GridLayout_Viewer);
            app.Label_2.Text = '片层';
            app.Slider_Slice = uislider(app.GridLayout_Viewer);
            app.Slider_Slice.Limits = [0 35];
            app.Slider_Slice.MajorTicks = [];
            app.Slider_Slice.ValueChangedFcn = createCallbackFcn(app, @Slider_SliceValueChanged, true);
            app.Slider_Slice.MinorTicks = [];
            app.Slider_Slice.Tooltip = {'片层微调'};
            app.GridLayout = uigridlayout(app.GridLayout_Viewer);
            app.Spinner_Time = uispinner(app.GridLayout);
            app.Spinner_Time.Limits = [1 Inf];
            app.Spinner_Time.ValueDisplayFormat = '%.0f';
            app.Spinner_Time.ValueChangedFcn = createCallbackFcn(app, @Spinner_TimeValueChanged, true);
            app.Spinner_Time.Tooltip = {'时间微调'};
            app.Spinner_Time.Value = 1;
            app.Spinner_Slice = uispinner(app.GridLayout_Viewer);
            app.Spinner_Slice.Limits = [1 Inf];
            app.Spinner_Slice.ValueDisplayFormat = '%.0f';
            app.Spinner_Slice.ValueChangedFcn = createCallbackFcn(app, @Spinner_SliceValueChanged, true);
            app.Spinner_Slice.Tooltip = {'片层微调'};
            app.Spinner_Slice.Value = 1;
            app.Image_Update = uiimage(app.GridLayout_Viewer);
            app.Image_Update.ImageClickedFcn = createCallbackFcn(app, @Image_UpdateClicked, true);
            app.Image_Update.Visible = 'off';
            app.Image_Update.ImageSource = fullfile(pathToMLAPP, 'sources', 'update_release.png');
            app.GridLayout5 = uigridlayout(app.GridLayout_Viewer);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.Image = uiimage(app.GridLayout6);
            app.Image.Enable = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'sources', 'right_arrow_mini.png');
            app.Button_HistoryManager = uibutton(app.GridLayout5, 'push');
            app.Button_HistoryManager.ButtonPushedFcn = createCallbackFcn(app, @Button_HistoryManagerPushed, true);
            app.Button_HistoryManager.Enable = 'off';
            app.Button_HistoryManager.Text = {'历'; '史'; '管'; '理'; '器'};
            app.GridLayout7 = uigridlayout(app.GridLayout_Viewer);
            app.UIAxes_Slice = uiaxes(app.GridLayout7);
            xlabel(app.UIAxes_Slice, 'X')
            ylabel(app.UIAxes_Slice, 'Y')
            zlabel(app.UIAxes_Slice, 'Z')
            app.UIAxes_Slice.Visible = 'off';
            app.GridLayout_OuterPB = uigridlayout(app.GridLayout7);
            app.GridLayout_ProgressBar = uigridlayout(app.GridLayout_OuterPB);
            app.Label_ProgressBar = uilabel(app.GridLayout_ProgressBar);
            app.Label_ProgressBar.Text = '';
            app.Label = uilabel(app.GridLayout_Viewer);
            app.Label.Text = '时间(帧)';
            app.Slider_Time = uislider(app.GridLayout_Viewer);
            app.Slider_Time.MajorTicks = [];
            app.Slider_Time.ValueChangedFcn = createCallbackFcn(app, @Slider_TimeValueChanged, true);
            app.Slider_Time.MinorTicks = [];
            app.Slider_Time.Tooltip = {'进度条'};
            app.Label_ImageInfo = uilabel(app.GridLayout_Viewer);
            app.Label_ImageInfo.Visible = 'off';
            app.Label_ImageInfo.Text = 'Image Information';
            app.ContextMenu_Player = uicontextmenu(app.Reg3DV212WukongUIFigure);
            app.Menu_Horizontal = uimenu(app.ContextMenu_Player);
            app.Menu_Horizontal.MenuSelectedFcn = createCallbackFcn(app, @Menu_HorizontalSelected, true);
            app.Menu_Horizontal.Enable = 'off';
            app.Menu_Horizontal.Text = '水平翻转';
            app.Menu_Vertical = uimenu(app.ContextMenu_Player);
            app.Menu_Vertical.MenuSelectedFcn = createCallbackFcn(app, @Menu_VerticalSelected, true);
            app.Menu_Vertical.Enable = 'off';
            app.Menu_Vertical.Text = '垂直翻转';
            app.Menu_ROI = uimenu(app.ContextMenu_Player);
            app.Menu_ROI.Enable = 'off';
            app.Menu_ROI.Text = '设置ROI';
            app.Menu_RectangleROI = uimenu(app.Menu_ROI);
            app.Menu_RectangleROI.MenuSelectedFcn = createCallbackFcn(app, @Menu_RectangleROISelected, true);
            app.Menu_RectangleROI.Text = '矩形';
            app.Menu_CircleROI = uimenu(app.Menu_ROI);
            app.Menu_CircleROI.MenuSelectedFcn = createCallbackFcn(app, @Menu_CircleROISelected, true);
            app.Menu_CircleROI.Text = '圆形';
            app.Menu_PolygonROI = uimenu(app.Menu_ROI);
            app.Menu_PolygonROI.MenuSelectedFcn = createCallbackFcn(app, @Menu_PolygonROISelected, true);
            app.Menu_PolygonROI.Text = '多边形';
            app.Menu_FreehandROI = uimenu(app.Menu_ROI);
            app.Menu_FreehandROI.MenuSelectedFcn = createCallbackFcn(app, @Menu_FreehandROISelected, true);
            app.Menu_FreehandROI.Text = '自由选择';
            app.Menu_Channel = uimenu(app.ContextMenu_Player);
            app.Menu_Channel.Enable = 'off';
            app.Menu_Channel.Text = '颜色通道';
            % Assign app.ContextMenu_Player
            app.GridLayout7.ContextMenu = app.ContextMenu_Player;
            app.ContextMenu_Controller = uicontextmenu(app.Reg3DV212WukongUIFigure);
            app.Menu_ExportLog = uimenu(app.ContextMenu_Controller);
            app.Menu_ExportLog.MenuSelectedFcn = createCallbackFcn(app, @Menu_ExportLogSelected, true);
            app.Menu_ExportLog.Text = '导出';
            app.Menu_ClearLog = uimenu(app.ContextMenu_Controller);
            app.Menu_ClearLog.MenuSelectedFcn = createCallbackFcn(app, @Menu_ClearLogSelected, true);
            app.Menu_ClearLog.Text = '清空';
            app.Menu_AutoClearLog = uimenu(app.ContextMenu_Controller);
            app.Menu_AutoClearLog.MenuSelectedFcn = createCallbackFcn(app, @Menu_AutoClearLogSelected, true);
            app.Menu_AutoClearLog.Text = '自动清空';
            % Assign app.ContextMenu_Controller
            app.TextArea_Log.ContextMenu = app.ContextMenu_Controller;
            % Show the figure after all components are created
            app.Reg3DV212WukongUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Register(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Reg3DV212WukongUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.Reg3DV212WukongUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Reg3DV212WukongUIFigure)
        end
    end
end